classdef SimulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SavedArraysPanel                matlab.ui.container.Panel
        ArrayNameEditField              matlab.ui.control.EditField
        ArrayNameEditFieldLabel         matlab.ui.control.Label
        ExportArrayButton               matlab.ui.control.Button
        LoadArrayButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CellViewTab                     matlab.ui.container.Tab
        ClearPlotButton                 matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        ALabel_2                        matlab.ui.control.Label
        VLabel_3                        matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        CellFullyDefinedLabel           matlab.ui.control.Label
        SingleCellSelectedLabel         matlab.ui.control.Label
        SingleCellSelectedLamp          matlab.ui.control.Lamp
        FullCurrentEditField            matlab.ui.control.NumericEditField
        FullCurrentEditFieldLabel       matlab.ui.control.Label
        MPPPowerEditField               matlab.ui.control.NumericEditField
        MPPPowerEditFieldLabel          matlab.ui.control.Label
        MPPCurrentEditField             matlab.ui.control.NumericEditField
        MPPCurrentEditFieldLabel        matlab.ui.control.Label
        MPPVoltageEditField             matlab.ui.control.NumericEditField
        MPPVoltageEditFieldLabel        matlab.ui.control.Label
        ShortCircuitCurrentEditField    matlab.ui.control.NumericEditField
        ShortCircuitCurrentEditFieldLabel  matlab.ui.control.Label
        OpenCircuitVoltageEditField     matlab.ui.control.NumericEditField
        OpenCircuitVoltageEditFieldLabel  matlab.ui.control.Label
        CellRollAngleGauge              matlab.ui.control.SemicircularGauge
        CellRollAngleGaugeLabel         matlab.ui.control.Label
        CellPitchAngleGauge             matlab.ui.control.SemicircularGauge
        CellPitchAngleGaugeLabel        matlab.ui.control.Label
        CellFullyDefinedLamp            matlab.ui.control.Lamp
        PlotCellButton                  matlab.ui.control.Button
        RollAngleEditField              matlab.ui.control.NumericEditField
        RollAngleEditFieldLabel         matlab.ui.control.Label
        PitchAngleEditField             matlab.ui.control.NumericEditField
        PitchAngleEditFieldLabel        matlab.ui.control.Label
        LocalCellParametersLabel        matlab.ui.control.Label
        WriteParametersButton_2         matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ModuleViewTab                   matlab.ui.container.Tab
        SelectedCellsFullyDefinedLabel  matlab.ui.control.Label
        ModuleFullyDefinedLamp          matlab.ui.control.Lamp
        ModuleColorLamp                 matlab.ui.control.Lamp
        ModuleColorLampLabel            matlab.ui.control.Label
        DeleteModuleButton              matlab.ui.control.Button
        MPPPowerEditField_2             matlab.ui.control.NumericEditField
        MPPPowerEditField_2Label        matlab.ui.control.Label
        MPPCurrentEditField_2           matlab.ui.control.NumericEditField
        MPPCurrentEditField_2Label      matlab.ui.control.Label
        MPPVoltageEditField_2           matlab.ui.control.NumericEditField
        MPPVoltageEditField_2Label      matlab.ui.control.Label
        ShortCircuitCurrentEditField_2  matlab.ui.control.NumericEditField
        ShortCircuitCurrentEditField_2Label  matlab.ui.control.Label
        OpenCircuitVoltageEditField_2   matlab.ui.control.NumericEditField
        OpenCircuitVoltageEditField_2Label  matlab.ui.control.Label
        WLabel_2                        matlab.ui.control.Label
        ALabel_5                        matlab.ui.control.Label
        VLabel_5                        matlab.ui.control.Label
        ALabel_4                        matlab.ui.control.Label
        VLabel_4                        matlab.ui.control.Label
        ClearPlotButton_2               matlab.ui.control.Button
        PlotModuleButton                matlab.ui.control.Button
        ModuleSizeEditField             matlab.ui.control.EditField
        ModuleSizeEditFieldLabel        matlab.ui.control.Label
        BypassDiodeSwitch               matlab.ui.control.ToggleSwitch
        BypassDiodeSwitchLabel          matlab.ui.control.Label
        AddcellstomoduleButton          matlab.ui.control.Button
        AddNewModuleButton              matlab.ui.control.Button
        ModuleNameEditField             matlab.ui.control.EditField
        ModuleNameEditFieldLabel        matlab.ui.control.Label
        ModuleListListBox               matlab.ui.control.ListBox
        ModuleListListBoxLabel          matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        SubarrayViewTab                 matlab.ui.container.Tab
        SubarrayColorLamp               matlab.ui.control.Lamp
        SubarrayColorLampLabel          matlab.ui.control.Label
        TopologyEfficiencyEditField     matlab.ui.control.NumericEditField
        TopologyEfficiencyEditFieldLabel  matlab.ui.control.Label
        CellsMaxPowerEditField          matlab.ui.control.NumericEditField
        CellsMaxPowerEditFieldLabel     matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        WLabel_4                        matlab.ui.control.Label
        MPPPowerEditField_3             matlab.ui.control.NumericEditField
        MPPPowerEditField_3Label        matlab.ui.control.Label
        MPPCurrentEditField_3           matlab.ui.control.NumericEditField
        MPPCurrentEditField_3Label      matlab.ui.control.Label
        MPPVoltageEditField_3           matlab.ui.control.NumericEditField
        MPPVoltageEditField_3Label      matlab.ui.control.Label
        ShortCircuitCurrentEditField_3  matlab.ui.control.NumericEditField
        ShortCircuitCurrentEditField_3Label  matlab.ui.control.Label
        OpenCircuitVoltageEditField_3   matlab.ui.control.NumericEditField
        OpenCircuitVoltageEditField_3Label  matlab.ui.control.Label
        WLabel_3                        matlab.ui.control.Label
        ALabel_7                        matlab.ui.control.Label
        VLabel_7                        matlab.ui.control.Label
        ALabel_6                        matlab.ui.control.Label
        VLabel_6                        matlab.ui.control.Label
        ClearPlotButton_3               matlab.ui.control.Button
        PlotSubarrayButton              matlab.ui.control.Button
        SubarraySizeEditField           matlab.ui.control.NumericEditField
        SubarraySizeEditFieldLabel      matlab.ui.control.Label
        SubarrayNameEditField           matlab.ui.control.EditField
        SubarrayNameEditFieldLabel      matlab.ui.control.Label
        SubarrayListListBox             matlab.ui.control.ListBox
        SubarrayListListBoxLabel        matlab.ui.control.Label
        DeleteSubarrayButton            matlab.ui.control.Button
        AddNewSubarrayButton            matlab.ui.control.Button
        MPPTProfileDropDown             matlab.ui.control.DropDown
        MPPTProfileDropDownLabel        matlab.ui.control.Label
        ModulesinSubarrayListBox        matlab.ui.control.ListBox
        ModulesinSubarrayListBoxLabel   matlab.ui.control.Label
        InsertIntoSubarrayButton        matlab.ui.control.Button
        RemoveFromSubarrayButton        matlab.ui.control.Button
        ModuleListListBox_2             matlab.ui.control.ListBox
        ModuleListListBox_2Label        matlab.ui.control.Label
        UIAxes_3                        matlab.ui.control.UIAxes
        ArrayViewTab                    matlab.ui.container.Tab
        ArrayPlannerPanel               matlab.ui.container.Panel
        SingleSelectButton              matlab.ui.control.StateButton
        DeselectAllButton               matlab.ui.control.Button
        SelectAllButton                 matlab.ui.control.Button
        Button_256                      matlab.ui.control.StateButton
        Button_255                      matlab.ui.control.StateButton
        Button_254                      matlab.ui.control.StateButton
        Button_257                      matlab.ui.control.StateButton
        Button_253                      matlab.ui.control.StateButton
        Button_252                      matlab.ui.control.StateButton
        Button_251                      matlab.ui.control.StateButton
        Button_250                      matlab.ui.control.StateButton
        Button_249                      matlab.ui.control.StateButton
        Button_247                      matlab.ui.control.StateButton
        Button_238                      matlab.ui.control.StateButton
        Button_229                      matlab.ui.control.StateButton
        Button_246                      matlab.ui.control.StateButton
        Button_237                      matlab.ui.control.StateButton
        Button_228                      matlab.ui.control.StateButton
        Button_245                      matlab.ui.control.StateButton
        Button_248                      matlab.ui.control.StateButton
        Button_236                      matlab.ui.control.StateButton
        Button_239                      matlab.ui.control.StateButton
        Button_227                      matlab.ui.control.StateButton
        Button_230                      matlab.ui.control.StateButton
        Button_244                      matlab.ui.control.StateButton
        Button_235                      matlab.ui.control.StateButton
        Button_226                      matlab.ui.control.StateButton
        Button_243                      matlab.ui.control.StateButton
        Button_242                      matlab.ui.control.StateButton
        Button_241                      matlab.ui.control.StateButton
        Button_240                      matlab.ui.control.StateButton
        Button_234                      matlab.ui.control.StateButton
        Button_233                      matlab.ui.control.StateButton
        Button_232                      matlab.ui.control.StateButton
        Button_231                      matlab.ui.control.StateButton
        Button_225                      matlab.ui.control.StateButton
        Button_224                      matlab.ui.control.StateButton
        Button_223                      matlab.ui.control.StateButton
        Button_222                      matlab.ui.control.StateButton
        Button_220                      matlab.ui.control.StateButton
        Button_219                      matlab.ui.control.StateButton
        Button_218                      matlab.ui.control.StateButton
        Button_221                      matlab.ui.control.StateButton
        Button_217                      matlab.ui.control.StateButton
        Button_216                      matlab.ui.control.StateButton
        Button_215                      matlab.ui.control.StateButton
        Button_214                      matlab.ui.control.StateButton
        Button_213                      matlab.ui.control.StateButton
        Button_211                      matlab.ui.control.StateButton
        Button_202                      matlab.ui.control.StateButton
        Button_193                      matlab.ui.control.StateButton
        Button_210                      matlab.ui.control.StateButton
        Button_201                      matlab.ui.control.StateButton
        Button_192                      matlab.ui.control.StateButton
        Button_209                      matlab.ui.control.StateButton
        Button_212                      matlab.ui.control.StateButton
        Button_200                      matlab.ui.control.StateButton
        Button_203                      matlab.ui.control.StateButton
        Button_191                      matlab.ui.control.StateButton
        Button_194                      matlab.ui.control.StateButton
        Button_208                      matlab.ui.control.StateButton
        Button_199                      matlab.ui.control.StateButton
        Button_190                      matlab.ui.control.StateButton
        Button_207                      matlab.ui.control.StateButton
        Button_206                      matlab.ui.control.StateButton
        Button_205                      matlab.ui.control.StateButton
        Button_204                      matlab.ui.control.StateButton
        Button_198                      matlab.ui.control.StateButton
        Button_197                      matlab.ui.control.StateButton
        Button_196                      matlab.ui.control.StateButton
        Button_195                      matlab.ui.control.StateButton
        Button_189                      matlab.ui.control.StateButton
        Button_188                      matlab.ui.control.StateButton
        Button_187                      matlab.ui.control.StateButton
        Button_186                      matlab.ui.control.StateButton
        Button_184                      matlab.ui.control.StateButton
        Button_183                      matlab.ui.control.StateButton
        Button_182                      matlab.ui.control.StateButton
        Button_185                      matlab.ui.control.StateButton
        Button_181                      matlab.ui.control.StateButton
        Button_180                      matlab.ui.control.StateButton
        Button_179                      matlab.ui.control.StateButton
        Button_178                      matlab.ui.control.StateButton
        Button_177                      matlab.ui.control.StateButton
        Button_175                      matlab.ui.control.StateButton
        Button_166                      matlab.ui.control.StateButton
        Button_157                      matlab.ui.control.StateButton
        Button_174                      matlab.ui.control.StateButton
        Button_165                      matlab.ui.control.StateButton
        Button_156                      matlab.ui.control.StateButton
        Button_173                      matlab.ui.control.StateButton
        Button_176                      matlab.ui.control.StateButton
        Button_164                      matlab.ui.control.StateButton
        Button_167                      matlab.ui.control.StateButton
        Button_155                      matlab.ui.control.StateButton
        Button_158                      matlab.ui.control.StateButton
        Button_172                      matlab.ui.control.StateButton
        Button_163                      matlab.ui.control.StateButton
        Button_154                      matlab.ui.control.StateButton
        Button_171                      matlab.ui.control.StateButton
        Button_170                      matlab.ui.control.StateButton
        Button_169                      matlab.ui.control.StateButton
        Button_168                      matlab.ui.control.StateButton
        Button_162                      matlab.ui.control.StateButton
        Button_161                      matlab.ui.control.StateButton
        Button_160                      matlab.ui.control.StateButton
        Button_159                      matlab.ui.control.StateButton
        Button_153                      matlab.ui.control.StateButton
        Button_152                      matlab.ui.control.StateButton
        Button_151                      matlab.ui.control.StateButton
        Button_150                      matlab.ui.control.StateButton
        Button_148                      matlab.ui.control.StateButton
        Button_147                      matlab.ui.control.StateButton
        Button_146                      matlab.ui.control.StateButton
        Button_149                      matlab.ui.control.StateButton
        Button_145                      matlab.ui.control.StateButton
        Button_144                      matlab.ui.control.StateButton
        Button_143                      matlab.ui.control.StateButton
        Button_142                      matlab.ui.control.StateButton
        Button_141                      matlab.ui.control.StateButton
        Button_139                      matlab.ui.control.StateButton
        Button_130                      matlab.ui.control.StateButton
        Button_121                      matlab.ui.control.StateButton
        Button_138                      matlab.ui.control.StateButton
        Button_129                      matlab.ui.control.StateButton
        Button_120                      matlab.ui.control.StateButton
        Button_137                      matlab.ui.control.StateButton
        Button_140                      matlab.ui.control.StateButton
        Button_128                      matlab.ui.control.StateButton
        Button_131                      matlab.ui.control.StateButton
        Button_119                      matlab.ui.control.StateButton
        Button_122                      matlab.ui.control.StateButton
        Button_136                      matlab.ui.control.StateButton
        Button_127                      matlab.ui.control.StateButton
        Button_118                      matlab.ui.control.StateButton
        Button_135                      matlab.ui.control.StateButton
        Button_134                      matlab.ui.control.StateButton
        Button_133                      matlab.ui.control.StateButton
        Button_132                      matlab.ui.control.StateButton
        Button_126                      matlab.ui.control.StateButton
        Button_125                      matlab.ui.control.StateButton
        Button_124                      matlab.ui.control.StateButton
        Button_123                      matlab.ui.control.StateButton
        Button_117                      matlab.ui.control.StateButton
        Button_116                      matlab.ui.control.StateButton
        Button_115                      matlab.ui.control.StateButton
        Button_114                      matlab.ui.control.StateButton
        Button_112                      matlab.ui.control.StateButton
        Button_103                      matlab.ui.control.StateButton
        Button_94                       matlab.ui.control.StateButton
        Button_84                       matlab.ui.control.StateButton
        Button_78                       matlab.ui.control.StateButton
        Button_72                       matlab.ui.control.StateButton
        Button_111                      matlab.ui.control.StateButton
        Button_102                      matlab.ui.control.StateButton
        Button_93                       matlab.ui.control.StateButton
        Button_110                      matlab.ui.control.StateButton
        Button_113                      matlab.ui.control.StateButton
        Button_101                      matlab.ui.control.StateButton
        Button_104                      matlab.ui.control.StateButton
        Button_92                       matlab.ui.control.StateButton
        Button_95                       matlab.ui.control.StateButton
        Button_86                       matlab.ui.control.StateButton
        Button_85                       matlab.ui.control.StateButton
        Button_80                       matlab.ui.control.StateButton
        Button_79                       matlab.ui.control.StateButton
        Button_74                       matlab.ui.control.StateButton
        Button_73                       matlab.ui.control.StateButton
        Button_68                       matlab.ui.control.StateButton
        Button_67                       matlab.ui.control.StateButton
        Button_64                       matlab.ui.control.StateButton
        Button_63                       matlab.ui.control.StateButton
        Button_60                       matlab.ui.control.StateButton
        Button_59                       matlab.ui.control.StateButton
        Button_56                       matlab.ui.control.StateButton
        Button_55                       matlab.ui.control.StateButton
        Button_52                       matlab.ui.control.StateButton
        Button_51                       matlab.ui.control.StateButton
        Button_48                       matlab.ui.control.StateButton
        Button_47                       matlab.ui.control.StateButton
        Button_44                       matlab.ui.control.StateButton
        Button_43                       matlab.ui.control.StateButton
        Button_40                       matlab.ui.control.StateButton
        Button_39                       matlab.ui.control.StateButton
        Button_36                       matlab.ui.control.StateButton
        Button_35                       matlab.ui.control.StateButton
        Button_30                       matlab.ui.control.StateButton
        Button_32                       matlab.ui.control.StateButton
        Button_31                       matlab.ui.control.StateButton
        Button_109                      matlab.ui.control.StateButton
        Button_100                      matlab.ui.control.StateButton
        Button_91                       matlab.ui.control.StateButton
        Button_108                      matlab.ui.control.StateButton
        Button_107                      matlab.ui.control.StateButton
        Button_106                      matlab.ui.control.StateButton
        Button_105                      matlab.ui.control.StateButton
        Button_99                       matlab.ui.control.StateButton
        Button_98                       matlab.ui.control.StateButton
        Button_97                       matlab.ui.control.StateButton
        Button_96                       matlab.ui.control.StateButton
        Button_90                       matlab.ui.control.StateButton
        Button_89                       matlab.ui.control.StateButton
        Button_88                       matlab.ui.control.StateButton
        Button_87                       matlab.ui.control.StateButton
        Button_83                       matlab.ui.control.StateButton
        Button_82                       matlab.ui.control.StateButton
        Button_81                       matlab.ui.control.StateButton
        Button_77                       matlab.ui.control.StateButton
        Button_76                       matlab.ui.control.StateButton
        Button_75                       matlab.ui.control.StateButton
        Button_71                       matlab.ui.control.StateButton
        Button_70                       matlab.ui.control.StateButton
        Button_69                       matlab.ui.control.StateButton
        Button_66                       matlab.ui.control.StateButton
        Button_65                       matlab.ui.control.StateButton
        Button_62                       matlab.ui.control.StateButton
        Button_61                       matlab.ui.control.StateButton
        Button_58                       matlab.ui.control.StateButton
        Button_57                       matlab.ui.control.StateButton
        Button_54                       matlab.ui.control.StateButton
        Button_53                       matlab.ui.control.StateButton
        Button_50                       matlab.ui.control.StateButton
        Button_49                       matlab.ui.control.StateButton
        Button_46                       matlab.ui.control.StateButton
        Button_45                       matlab.ui.control.StateButton
        Button_42                       matlab.ui.control.StateButton
        Button_41                       matlab.ui.control.StateButton
        Button_38                       matlab.ui.control.StateButton
        Button_37                       matlab.ui.control.StateButton
        Button_34                       matlab.ui.control.StateButton
        Button_33                       matlab.ui.control.StateButton
        Button_29                       matlab.ui.control.StateButton
        Button_28                       matlab.ui.control.StateButton
        Button_27                       matlab.ui.control.StateButton
        Button_19                       matlab.ui.control.StateButton
        Button_11                       matlab.ui.control.StateButton
        Button_26                       matlab.ui.control.StateButton
        Button_18                       matlab.ui.control.StateButton
        Button_25                       matlab.ui.control.StateButton
        Button_24                       matlab.ui.control.StateButton
        Button_17                       matlab.ui.control.StateButton
        Button_16                       matlab.ui.control.StateButton
        Button_10                       matlab.ui.control.StateButton
        Button_9                        matlab.ui.control.StateButton
        Button_23                       matlab.ui.control.StateButton
        Button_22                       matlab.ui.control.StateButton
        Button_21                       matlab.ui.control.StateButton
        Button_20                       matlab.ui.control.StateButton
        Button_15                       matlab.ui.control.StateButton
        Button_14                       matlab.ui.control.StateButton
        Button_13                       matlab.ui.control.StateButton
        Button_12                       matlab.ui.control.StateButton
        Button_8                        matlab.ui.control.StateButton
        Button_7                        matlab.ui.control.StateButton
        Button_6                        matlab.ui.control.StateButton
        Button_5                        matlab.ui.control.StateButton
        Button_4                        matlab.ui.control.StateButton
        Button_3                        matlab.ui.control.StateButton
        Button_2                        matlab.ui.control.StateButton
        Button_1                        matlab.ui.control.StateButton
        GlobalParametersPanel           matlab.ui.container.Panel
        LightIntensitySlider_2Label_3   matlab.ui.control.Label
        LightIntensitySlider_2Label_2   matlab.ui.control.Label
        SunAzimuthSlider                matlab.ui.control.Slider
        SunZenithSlider                 matlab.ui.control.Slider
        LightIntensitySlider            matlab.ui.control.Slider
        LightIntensitySlider_2Label     matlab.ui.control.Label
        TemperatureCEditField           matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel      matlab.ui.control.Label
        MaxOpticalCurrentEditField      matlab.ui.control.NumericEditField
        MaxOpticalCurrentEditFieldLabel  matlab.ui.control.Label
        SeriesResistanceEditField       matlab.ui.control.NumericEditField
        SeriesResistanceEditFieldLabel  matlab.ui.control.Label
        SaturationCurrentEditField      matlab.ui.control.NumericEditField
        SaturationCurrentEditFieldLabel  matlab.ui.control.Label
        IdealityFactorEditField         matlab.ui.control.NumericEditField
        IdealityFactorEditFieldLabel    matlab.ui.control.Label
        GlobalCellParametersLabel       matlab.ui.control.Label
        WriteParametersButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        CellList        SolarCell
        moduleList      Module
        subArrayList    SubArray

        CellSelectionList = zeros(257, 1)

        numCells = 257
        numModules = 0
        numSubarrays = 0
        
        DefaultButtonColor = [0.96,0.96,0.96]
        ColorOrder = {'#D95319', '#EDB120', '#7E2F8E', '#77AC30', '#4DBEEE', '#A2142F'}

        cellButtonList

        
        ViewMode = "Cell View"
        ActiveCellNum = 1
        
    end
    
    methods (Access = private)
        
        function PlotCell(app, cell)
            if app.ViewMode == 1
                if (cell.fullyDefined)
                    I = linspace(-1, 10, 1101);
                    effLi = cell.ComputeEffLi(app.userLightIntensity, app.userSunZenith, app.userSunAzimuth);
                    V = cell.viMatrix(cell.liIndex(effLi), :);
    
                    plot(app.UIAxes, V, I)
                    [app.MPPVoltageEditField, app.MPPCurrentEditField, app.MPPPowerEditField_2.Value] = cell.GetMPP(app.userLightIntensity);
                    app.ShortCircuitCurrentEditField = cell.GetIsc(app.userLightIntensity, app.userSunZenith, app.userSunAzimuth);
                    app.OpenCircuitVoltageeditField = cell.GetVoc(app.userLightIntensity, app.userSunZenith, app.userSunAzimuth);
                end
            end
        end
        
        function PlotModule(app)
            
        end

        function PlotSubArray(app)
            
        end

        
        function ColorCellButtons(app, colorList)
            for i = 1:size(colorList, 2)
                app.cellButtonList(i).BackgroundColor = colorList{i};
            end
        end
        
        function SelectCell(app, cellNum)

            if app.ViewMode == "Cell View"

                if app.SingleSelectButton.Value == 1
                    % If single-select mode is on, deselect all other
                    % selected cells first
                    for i=1:257
                        if app.CellSelectionList(i) == 1
                            app.DeselectCell(i);
                        end
                    end
                end

                % Update button value
                app.cellButtonList(cellNum).Value = 1;
                
                % Update CellSelectionList
                app.CellSelectionList(cellNum) = 1;

                % Update ActiveCellNum
                app.ActiveCellNum = cellNum;

                % Update angle fields and gauges
                if app.CellList(cellNum).fullyDefined
                    app.PitchAngleEditField.Value = app.CellList(cellNum).theta;
                    app.RollAngleEditField.Value = app.CellList(cellNum).phi;
                    app.CellPitchAngleGauge.Value = app.CellList(cellNum).theta;
                    app.CellRollAngleGauge.Value = app.CellList(cellNum).phi;
                end
                
                % Update status lamps
                if (sum(app.CellSelectionList) == 1)
                    app.SingleCellSelectedLamp.Color = 'g';
                else
                    app.SingleCellSelectedLamp.Color = 'r';
                end
                if app.CellList(cellNum).fullyDefined
                    app.CellFullyDefinedLamp.Color = 'g';
                else
                    app.CellFullyDefinedLamp.Color = 'r';
                end

            elseif app.ViewMode == "Module View"

                % Update button value
                app.cellButtonList(cellNum).Value = 1;
                
                % Update CellSelectionList
                app.CellSelectionList(cellNum) = 1;

                % Set selected cell(s) fully defined lamp
                LampColor = 'g';
                for i=1:257
                    if app.CellSelectionList(i)
                        if app.CellList(i).fullyDefined == 0
                            LampColor = 'r';
                        end
                    end
                end
                app.ModuleFullyDefinedLamp.Color = LampColor;

                % Make add cell(s) to module button clickable
                if LampColor == 'g'
                    app.AddcellstomoduleButton.Enable = 'on';
                else
                    app.AddcellstomoduleButton.Enable = 'off';
                end

            elseif app.ViewMode == "Subarray View"
            elseif app.ViewMode == "Array View"
            end

        end
        
        function DeselectCell(app, cellNum)

            if app.ViewMode == "Cell View"
                % Update CellSelectionList
                app.CellSelectionList(cellNum) = 0;

                % Revert cell button color
                app.cellButtonList(cellNum).Value = 0;

                % Update ActiveCellNum
                if (app.ActiveCellNum == cellNum)
                    app.ActiveCellNum = find(app.CellSelectionList, 1);
                end

                % Update Single Cell Selected Lamp
                if (sum(app.CellSelectionList) == 1)
                    app.SingleCellSelectedLamp.Color = 'g';
                else
                    app.SingleCellSelectedLamp.Color = 'r';
                end
                
            elseif app.ViewMode == "Module View"

                % Update CellSelectionList
                app.CellSelectionList(cellNum) = 0;

                % Revert cell button color
                app.cellButtonList(cellNum).Value = 0;

                % Set selected cell(s) fully defined lamp
                if sum(app.CellSelectionList) > 0
                    LampColor = 'g';
                    for i=1:257
                        if app.CellSelectionList(i)
                            if app.CellList(i).fullyDefined == 0
                                LampColor = 'r';
                            end
                        end
                    end
                else
                    LampColor = 'r';
                end
                app.ModuleFullyDefinedLamp.Color = LampColor;

                % Make add cell(s) to module button clickable
                if LampColor == 'g'
                    app.AddcellstomoduleButton.Enable = 'on';
                else
                    app.AddcellstomoduleButton.Enable = 'off';
                end

            elseif app.ViewMode == "Subarray View"
            elseif app.ViewMode == "Array View"
            end
            
        end
        
        function ColorModules(app)

            % If there are more than 0 modules
            if size(app.ModuleListListBox.Items, 2) > 0
            
                % Highlight current modules with module colors
                % Iterate through every module
                for i = 1:size(app.ModuleListListBox.ItemsData, 2)
                    currentColor = app.ModuleListListBox.ItemsData(i).color;
                    % Iterate through every cell in module
                    for j = 1:size(app.ModuleListListBox.ItemsData(i).SolarObjects, 2)
                        app.cellButtonList(app.ModuleListListBox.ItemsData(i).SolarObjects{j}.id).BackgroundColor = currentColor;
                    end
                end
            end

        end
        
        function ColorSubarrays(app)
            
            % If there are more than 0 subarrays
            if size(app.SubarrayListListBox.Items, 2) > 0
            
                % Highlight current subarrays with subarray colors
                % Iterate through every subarray
                for i = 1:size(app.SubarrayListListBox.ItemsData, 2)
                    currentColor = app.SubarrayListListBox.ItemsData(i).color;
                    % Iterate through modules in subarray
                    for j = 1:size(app.SubarrayListListBox.ItemsData(i).SolarObjects, 2)
                        % Iterate through every cell in module
                        for k = 1:size(app.SubarrayListListBox.ItemsData(i).SolarObjects{j}.SolarObjects, 2)
                            app.cellButtonList(app.SubarrayListListBox.ItemsData(i).SolarObjects{j}.SolarObjects{k}.id).BackgroundColor = currentColor;
                        end
                    end
                end

            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Create 257 blank SolarCell objects with ascending ID
            for i = 1:257
                app.CellList(i) = SolarCell.CreateCell(i);
            end

            % Add cell buttons to cellButtonList
            app.cellButtonList = [app.Button_1, app.Button_2, app.Button_3, app.Button_4,...
                                  app.Button_5, app.Button_6, app.Button_7, app.Button_8,...
                                  app.Button_9, app.Button_10, app.Button_11, app.Button_12,...
                                  app.Button_13, app.Button_14, app.Button_15, app.Button_16,...
                                  app.Button_17, app.Button_18, app.Button_19, app.Button_20,...
                                  app.Button_21, app.Button_22, app.Button_23, app.Button_24,...
                                  app.Button_25, app.Button_26, app.Button_27, app.Button_28,...
                                  app.Button_29, app.Button_30, app.Button_31, app.Button_32,...
                                  app.Button_33, app.Button_34, app.Button_35, app.Button_36,...
                                  app.Button_37, app.Button_38, app.Button_39, app.Button_40,...
                                  app.Button_41, app.Button_42, app.Button_43, app.Button_44,...
                                  app.Button_45, app.Button_46, app.Button_47, app.Button_48,...
                                  app.Button_49, app.Button_50, app.Button_51, app.Button_52,...
                                  app.Button_53, app.Button_54, app.Button_55, app.Button_56,...
                                  app.Button_57, app.Button_58, app.Button_59, app.Button_60,...
                                  app.Button_61, app.Button_62, app.Button_63, app.Button_64,...
                                  app.Button_65, app.Button_66, app.Button_67, app.Button_68,...
                                  app.Button_69, app.Button_70, app.Button_71, app.Button_72,...
                                  app.Button_73, app.Button_74, app.Button_75, app.Button_76,...
                                  app.Button_77, app.Button_78, app.Button_79, app.Button_80,...
                                  app.Button_81, app.Button_82, app.Button_83, app.Button_84,...
                                  app.Button_85, app.Button_86, app.Button_87, app.Button_88,...
                                  app.Button_89, app.Button_90, app.Button_91, app.Button_92,...
                                  app.Button_93, app.Button_94, app.Button_95, app.Button_96,...
                                  app.Button_97, app.Button_98, app.Button_97, app.Button_100,...
                                  app.Button_101, app.Button_102, app.Button_103, app.Button_104,...
                                  app.Button_105, app.Button_106, app.Button_105, app.Button_108,...
                                  app.Button_109, app.Button_110, app.Button_109, app.Button_112,...
                                  app.Button_113, app.Button_114, app.Button_113, app.Button_116,...
                                  app.Button_117, app.Button_118, app.Button_117, app.Button_120,...
                                  app.Button_121, app.Button_122, app.Button_121, app.Button_124,...
                                  app.Button_125, app.Button_126, app.Button_125, app.Button_128,...
                                  app.Button_129, app.Button_130, app.Button_129, app.Button_132,...
                                  app.Button_133, app.Button_134, app.Button_133, app.Button_136,...
                                  app.Button_137, app.Button_138, app.Button_137, app.Button_140,...
                                  app.Button_141, app.Button_142, app.Button_141, app.Button_144,...
                                  app.Button_145, app.Button_146, app.Button_145, app.Button_148,...
                                  app.Button_149, app.Button_150, app.Button_149, app.Button_152,...
                                  app.Button_153, app.Button_154, app.Button_153, app.Button_156,...
                                  app.Button_157, app.Button_158, app.Button_157, app.Button_160,...
                                  app.Button_161, app.Button_162, app.Button_161, app.Button_164,...
                                  app.Button_165, app.Button_166, app.Button_165, app.Button_168,...
                                  app.Button_169, app.Button_170, app.Button_169, app.Button_172,...
                                  app.Button_173, app.Button_174, app.Button_173, app.Button_176,...
                                  app.Button_177, app.Button_178, app.Button_177, app.Button_180,...
                                  app.Button_181, app.Button_182, app.Button_181, app.Button_184,...
                                  app.Button_185, app.Button_186, app.Button_185, app.Button_188,...
                                  app.Button_189, app.Button_190, app.Button_189, app.Button_192,...
                                  app.Button_193, app.Button_194, app.Button_193, app.Button_196,...
                                  app.Button_197, app.Button_198, app.Button_199, app.Button_200,...
                                  app.Button_201, app.Button_202, app.Button_203, app.Button_204,...
                                  app.Button_205, app.Button_206, app.Button_205, app.Button_208,...
                                  app.Button_209, app.Button_210, app.Button_209, app.Button_212,...
                                  app.Button_213, app.Button_214, app.Button_213, app.Button_216,...
                                  app.Button_217, app.Button_218, app.Button_217, app.Button_220,...
                                  app.Button_221, app.Button_222, app.Button_221, app.Button_224,...
                                  app.Button_225, app.Button_226, app.Button_225, app.Button_228,...
                                  app.Button_229, app.Button_230, app.Button_229, app.Button_232,...
                                  app.Button_233, app.Button_234, app.Button_233, app.Button_236,...
                                  app.Button_237, app.Button_238, app.Button_237, app.Button_240,...
                                  app.Button_241, app.Button_242, app.Button_241, app.Button_244,...
                                  app.Button_245, app.Button_246, app.Button_245, app.Button_248,...
                                  app.Button_249, app.Button_250, app.Button_249, app.Button_252,...
                                  app.Button_253, app.Button_254, app.Button_255, app.Button_256,...
                                  app.Button_257];
        end

        % Value changed function: Button_1
        function Button_1ValueChanged(app, event)
            cellNum = 1;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            cellNum = 2;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            cellNum = 3;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            cellNum = 4;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            cellNum = 5;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            cellNum = 6;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
            cellNum = 7;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
            cellNum = 8;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            cellNum = 9;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_10
        function Button_10ValueChanged(app, event)
            cellNum = 10;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_11
        function Button_11ValueChanged(app, event)
            cellNum = 11;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_12
        function Button_12ValueChanged(app, event)
            cellNum = 12;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_13
        function Button_13ValueChanged(app, event)
            cellNum = 13;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_14
        function Button_14ValueChanged(app, event)
            cellNum = 14;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_15
        function Button_15ValueChanged(app, event)
            cellNum = 15;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_16
        function Button_16ValueChanged(app, event)
            cellNum = 16;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_17
        function Button_17ValueChanged(app, event)
            cellNum = 17;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_18
        function Button_18ValueChanged(app, event)
            cellNum = 18;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_19
        function Button_19ValueChanged(app, event)
            cellNum = 19;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_20
        function Button_20ValueChanged(app, event)
            cellNum = 20;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_21
        function Button_21ValueChanged(app, event)
            cellNum = 21;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_22
        function Button_22ValueChanged(app, event)
            cellNum = 22;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_23
        function Button_23ValueChanged(app, event)
            cellNum = 23;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_24
        function Button_24ValueChanged(app, event)
            cellNum = 24;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_25
        function Button_25ValueChanged(app, event)
            cellNum = 25;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_26
        function Button_26ValueChanged(app, event)
            cellNum = 26;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_27
        function Button_27ValueChanged(app, event)
            cellNum = 27;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_28
        function Button_28ValueChanged(app, event)
            cellNum = 28;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_29
        function Button_29ValueChanged(app, event)
            cellNum = 29;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_30
        function Button_30ValueChanged(app, event)
            cellNum = 30;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_31
        function Button_31ValueChanged(app, event)
            cellNum = 31;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_32
        function Button_32ValueChanged(app, event)
            cellNum = 32;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_33
        function Button_33ValueChanged(app, event)
            cellNum = 33;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_34
        function Button_34ValueChanged(app, event)
            cellNum = 34;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_35
        function Button_35ValueChanged(app, event)
            cellNum = 35;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_36
        function Button_36ValueChanged(app, event)
            cellNum = 36;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_37
        function Button_37ValueChanged(app, event)
            cellNum = 37;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_38
        function Button_38ValueChanged(app, event)
            cellNum = 38;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_39
        function Button_39ValueChanged(app, event)
            cellNum = 39;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_40
        function Button_40ValueChanged(app, event)
            cellNum = 40;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_41
        function Button_41ValueChanged(app, event)
            cellNum = 41;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_42
        function Button_42ValueChanged(app, event)
            cellNum = 42;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_43
        function Button_43ValueChanged(app, event)
            cellNum = 43;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_44
        function Button_44ValueChanged(app, event)
            cellNum = 44;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_45
        function Button_45ValueChanged(app, event)
            cellNum = 45;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_46
        function Button_46ValueChanged(app, event)
            cellNum = 46;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_47
        function Button_47ValueChanged(app, event)
            cellNum = 47;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_48
        function Button_48ValueChanged(app, event)
            cellNum = 48;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_49
        function Button_49ValueChanged(app, event)
            cellNum = 49;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_50
        function Button_50ValueChanged(app, event)
            cellNum = 50;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_51
        function Button_51ValueChanged(app, event)
            cellNum = 51;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_52
        function Button_52ValueChanged(app, event)
            cellNum = 52;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_53
        function Button_53ValueChanged(app, event)
            cellNum = 53;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_54
        function Button_54ValueChanged(app, event)
            cellNum = 54;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_55
        function Button_55ValueChanged(app, event)
            cellNum = 55;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_56
        function Button_56ValueChanged(app, event)
            cellNum = 56;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_57
        function Button_57ValueChanged(app, event)
            cellNum = 57;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_58
        function Button_58ValueChanged(app, event)
            cellNum = 58;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_59
        function Button_59ValueChanged(app, event)
            cellNum = 59;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_60
        function Button_60ValueChanged(app, event)
            cellNum = 60;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_61
        function Button_61ValueChanged(app, event)
            cellNum = 61;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_62
        function Button_62ValueChanged(app, event)
            cellNum = 62;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_63
        function Button_63ValueChanged(app, event)
            cellNum = 63;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_64
        function Button_64ValueChanged(app, event)
            cellNum = 64;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_65
        function Button_65ValueChanged(app, event)
            cellNum = 65;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_66
        function Button_66ValueChanged(app, event)
            cellNum = 66;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_67
        function Button_67ValueChanged(app, event)
            cellNum = 67;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_68
        function Button_68ValueChanged(app, event)
            cellNum = 68;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_69
        function Button_69ValueChanged(app, event)
            cellNum = 69;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_70
        function Button_70ValueChanged(app, event)
            cellNum = 70;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_71
        function Button_71ValueChanged(app, event)
            cellNum = 71;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_72
        function Button_72ValueChanged(app, event)
            cellNum = 72;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_73
        function Button_73ValueChanged(app, event)
            cellNum = 73;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_74
        function Button_74ValueChanged(app, event)
            cellNum = 74;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_75
        function Button_75ValueChanged(app, event)
            cellNum = 75;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_76
        function Button_76ValueChanged(app, event)
            cellNum = 76;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_77
        function Button_77ValueChanged(app, event)
            cellNum = 77;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_78
        function Button_78ValueChanged(app, event)
            cellNum = 78;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_79
        function Button_79ValueChanged(app, event)
            cellNum = 79;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_80
        function Button_80ValueChanged(app, event)
            cellNum = 80;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_81
        function Button_81ValueChanged(app, event)
            cellNum = 81;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_82
        function Button_82ValueChanged(app, event)
            cellNum = 82;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_83
        function Button_83ValueChanged(app, event)
            cellNum = 83;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_84
        function Button_84ValueChanged(app, event)
            cellNum = 84;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_85
        function Button_85ValueChanged(app, event)
            cellNum = 85;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_86
        function Button_86ValueChanged(app, event)
            cellNum = 86;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_87
        function Button_87ValueChanged(app, event)
            cellNum = 87;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_88
        function Button_88ValueChanged(app, event)
            cellNum = 88;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_89
        function Button_89ValueChanged(app, event)
            cellNum = 89;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_90
        function Button_90ValueChanged(app, event)
            cellNum = 90;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_91
        function Button_91ValueChanged(app, event)
            cellNum = 91;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_92
        function Button_92ValueChanged(app, event)
            cellNum = 92;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_93
        function Button_93ValueChanged(app, event)
            cellNum = 93;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_94
        function Button_94ValueChanged(app, event)
            cellNum = 94;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_95
        function Button_95ValueChanged(app, event)
            cellNum = 95;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_96
        function Button_96ValueChanged(app, event)
            cellNum = 96;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_97
        function Button_97ValueChanged(app, event)
            cellNum = 97;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_98
        function Button_98ValueChanged(app, event)
            cellNum = 98;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_99
        function Button_99ValueChanged(app, event)
            cellNum = 99;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_100
        function Button_100ValueChanged(app, event)
            cellNum = 100;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_101
        function Button_101ValueChanged(app, event)
            cellNum = 101;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_102
        function Button_102ValueChanged(app, event)
            cellNum = 102;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_103
        function Button_103ValueChanged(app, event)
            cellNum = 103;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_104
        function Button_104ValueChanged(app, event)
            cellNum = 104;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_105
        function Button_105ValueChanged(app, event)
            cellNum = 105;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_106
        function Button_106ValueChanged(app, event)
            cellNum = 106;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_107
        function Button_107ValueChanged(app, event)
            cellNum = 107;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_108
        function Button_108ValueChanged(app, event)
            cellNum = 108;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_109
        function Button_109ValueChanged(app, event)
            cellNum = 109;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_110
        function Button_110ValueChanged(app, event)
            cellNum = 110;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_111
        function Button_111ValueChanged(app, event)
            cellNum = 111;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_112
        function Button_112ValueChanged(app, event)
            cellNum = 112;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_113
        function Button_113ValueChanged(app, event)
            cellNum = 113;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_114
        function Button_114ValueChanged(app, event)
            cellNum = 114;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_115
        function Button_115ValueChanged(app, event)
            cellNum = 115;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_116
        function Button_116ValueChanged(app, event)
            cellNum = 116;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_117
        function Button_117ValueChanged(app, event)
            cellNum = 117;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_118
        function Button_118ValueChanged(app, event)
            cellNum = 118;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_119
        function Button_119ValueChanged(app, event)
            cellNum = 119;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_120
        function Button_120ValueChanged(app, event)
            cellNum = 120;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_121
        function Button_121ValueChanged(app, event)
            cellNum = 121;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_122
        function Button_122ValueChanged(app, event)
            cellNum = 122;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_123
        function Button_123ValueChanged(app, event)
            cellNum = 123;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_124
        function Button_124ValueChanged(app, event)
            cellNum = 124;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_125
        function Button_125ValueChanged(app, event)
            cellNum = 125;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_126
        function Button_126ValueChanged(app, event)
            cellNum = 126;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_127
        function Button_127ValueChanged(app, event)
            cellNum = 127;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_128
        function Button_128ValueChanged(app, event)
            cellNum = 128;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_129
        function Button_129ValueChanged(app, event)
            cellNum = 129;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_130
        function Button_130ValueChanged(app, event)
            cellNum = 130;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_131
        function Button_131ValueChanged(app, event)
            cellNum = 131;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_132
        function Button_132ValueChanged(app, event)
            cellNum = 132;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_133
        function Button_133ValueChanged(app, event)
            cellNum = 133;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_134
        function Button_134ValueChanged(app, event)
            cellNum = 134;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_135
        function Button_135ValueChanged(app, event)
            cellNum = 135;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_136
        function Button_136ValueChanged(app, event)
            cellNum = 136;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_137
        function Button_137ValueChanged(app, event)
            cellNum = 137;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_138
        function Button_138ValueChanged(app, event)
            cellNum = 138;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_139
        function Button_139ValueChanged(app, event)
            cellNum = 139;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_140
        function Button_140ValueChanged(app, event)
            cellNum = 140;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_141
        function Button_141ValueChanged(app, event)
            cellNum = 141;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_142
        function Button_142ValueChanged(app, event)
            cellNum = 142;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_143
        function Button_143ValueChanged(app, event)
            cellNum = 143;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_144
        function Button_144ValueChanged(app, event)
            cellNum = 144;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_145
        function Button_145ValueChanged(app, event)
            cellNum = 145;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_146
        function Button_146ValueChanged(app, event)
            cellNum = 146;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_147
        function Button_147ValueChanged(app, event)
            cellNum = 147;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_148
        function Button_148ValueChanged(app, event)
            cellNum = 148;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_149
        function Button_149ValueChanged(app, event)
            cellNum = 149;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_150
        function Button_150ValueChanged(app, event)
            cellNum = 150;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_151
        function Button_151ValueChanged(app, event)
            cellNum = 151;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_152
        function Button_152ValueChanged(app, event)
            cellNum = 152;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_153
        function Button_153ValueChanged(app, event)
            cellNum = 153;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_154
        function Button_154ValueChanged(app, event)
            cellNum = 154;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_155
        function Button_155ValueChanged(app, event)
            cellNum = 155;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_156
        function Button_156ValueChanged(app, event)
            cellNum = 156;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_157
        function Button_157ValueChanged(app, event)
            cellNum = 157;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_158
        function Button_158ValueChanged(app, event)
            cellNum = 158;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_159
        function Button_159ValueChanged(app, event)
            cellNum = 159;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_160
        function Button_160ValueChanged(app, event)
            cellNum = 160;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_161
        function Button_161ValueChanged(app, event)
            cellNum = 161;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_162
        function Button_162ValueChanged(app, event)
            cellNum = 162;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_163
        function Button_163ValueChanged(app, event)
            cellNum = 163;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_164
        function Button_164ValueChanged(app, event)
            cellNum = 164;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_165
        function Button_165ValueChanged(app, event)
            cellNum = 165;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_166
        function Button_166ValueChanged(app, event)
            cellNum = 166;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_167
        function Button_167ValueChanged(app, event)
            cellNum = 167;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_168
        function Button_168ValueChanged(app, event)
            cellNum = 168;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_169
        function Button_169ValueChanged(app, event)
            cellNum = 169;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_170
        function Button_170ValueChanged(app, event)
            cellNum = 170;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_171
        function Button_171ValueChanged(app, event)
            cellNum = 171;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_172
        function Button_172ValueChanged(app, event)
            cellNum = 172;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_173
        function Button_173ValueChanged(app, event)
            cellNum = 173;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_174
        function Button_174ValueChanged(app, event)
            cellNum = 174;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_175
        function Button_175ValueChanged(app, event)
            cellNum = 175;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_176
        function Button_176ValueChanged(app, event)
            cellNum = 176;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_177
        function Button_177ValueChanged(app, event)
            cellNum = 177;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_178
        function Button_178ValueChanged(app, event)
            cellNum = 178;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_179
        function Button_179ValueChanged(app, event)
            cellNum = 179;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_180
        function Button_180ValueChanged(app, event)
            cellNum = 180;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_181
        function Button_181ValueChanged(app, event)
            cellNum = 181;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_182
        function Button_182ValueChanged(app, event)
            cellNum = 182;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_183
        function Button_183ValueChanged(app, event)
            cellNum = 183;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_184
        function Button_184ValueChanged(app, event)
            cellNum = 184;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_185
        function Button_185ValueChanged(app, event)
            cellNum = 185;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_186
        function Button_186ValueChanged(app, event)
            cellNum = 186;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_187
        function Button_187ValueChanged(app, event)
            cellNum = 187;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_188
        function Button_188ValueChanged(app, event)
            cellNum = 188;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_189
        function Button_189ValueChanged(app, event)
            cellNum = 189;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_190
        function Button_190ValueChanged(app, event)
            cellNum = 190;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_191
        function Button_191ValueChanged(app, event)
            cellNum = 191;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_192
        function Button_192ValueChanged(app, event)
            cellNum = 192;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_193
        function Button_193ValueChanged(app, event)
            cellNum = 193;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_194
        function Button_194ValueChanged(app, event)
            cellNum = 194;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_195
        function Button_195ValueChanged(app, event)
            cellNum = 195;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_196
        function Button_196ValueChanged(app, event)
            cellNum = 196;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_197
        function Button_197ValueChanged(app, event)
            cellNum = 197;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_198
        function Button_198ValueChanged(app, event)
            cellNum = 198;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_199
        function Button_199ValueChanged(app, event)
            cellNum = 199;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_200
        function Button_200ValueChanged(app, event)
            cellNum = 200;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_201
        function Button_201ValueChanged(app, event)
            cellNum = 201;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_202
        function Button_202ValueChanged(app, event)
            cellNum = 202;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_203
        function Button_203ValueChanged(app, event)
            cellNum = 203;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_204
        function Button_204ValueChanged(app, event)
            cellNum = 204;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_205
        function Button_205ValueChanged(app, event)
            cellNum = 205;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_206
        function Button_206ValueChanged(app, event)
            cellNum = 206;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_207
        function Button_207ValueChanged(app, event)
            cellNum = 207;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_208
        function Button_208ValueChanged(app, event)
            cellNum = 208;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_209
        function Button_209ValueChanged(app, event)
            cellNum = 209;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_210
        function Button_210ValueChanged(app, event)
            cellNum = 210;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_211
        function Button_211ValueChanged(app, event)
            cellNum = 211;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_212
        function Button_212ValueChanged(app, event)
            cellNum = 212;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_213
        function Button_213ValueChanged(app, event)
            cellNum = 213;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_214
        function Button_214ValueChanged(app, event)
            cellNum = 214;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_215
        function Button_215ValueChanged(app, event)
            cellNum = 215;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_216
        function Button_216ValueChanged(app, event)
            cellNum = 216;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_217
        function Button_217ValueChanged(app, event)
            cellNum = 217;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_218
        function Button_218ValueChanged(app, event)
            cellNum = 218;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_219
        function Button_219ValueChanged(app, event)
            cellNum = 219;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_220
        function Button_220ValueChanged(app, event)
            cellNum = 220;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_221
        function Button_221ValueChanged(app, event)
            cellNum = 221;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_222
        function Button_222ValueChanged(app, event)
            cellNum = 222;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_223
        function Button_223ValueChanged(app, event)
            cellNum = 223;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_224
        function Button_224ValueChanged(app, event)
            cellNum = 224;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_225
        function Button_225ValueChanged(app, event)
            cellNum = 225;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_226
        function Button_226ValueChanged(app, event)
            cellNum = 226;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_227
        function Button_227ValueChanged(app, event)
            cellNum = 227;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_228
        function Button_228ValueChanged(app, event)
            cellNum = 228;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_229
        function Button_229ValueChanged(app, event)
            cellNum = 229;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_230
        function Button_230ValueChanged(app, event)
            cellNum = 230;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_231
        function Button_231ValueChanged(app, event)
            cellNum = 231;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_232
        function Button_232ValueChanged(app, event)
            cellNum = 232;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_233
        function Button_233ValueChanged(app, event)
            cellNum = 233;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_234
        function Button_234ValueChanged(app, event)
            cellNum = 234;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_235
        function Button_235ValueChanged(app, event)
            cellNum = 235;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_236
        function Button_236ValueChanged(app, event)
            cellNum = 236;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_237
        function Button_237ValueChanged(app, event)
            cellNum = 237;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_238
        function Button_238ValueChanged(app, event)
            cellNum = 238;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_239
        function Button_239ValueChanged(app, event)
            cellNum = 239;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_240
        function Button_240ValueChanged(app, event)
            cellNum = 240;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_241
        function Button_241ValueChanged(app, event)
            cellNum = 241;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_242
        function Button_242ValueChanged(app, event)
            cellNum = 242;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_243
        function Button_243ValueChanged(app, event)
            cellNum = 243;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_244
        function Button_244ValueChanged(app, event)
            cellNum = 244;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_245
        function Button_245ValueChanged(app, event)
            cellNum = 245;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_246
        function Button_246ValueChanged(app, event)
            cellNum = 246;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_247
        function Button_247ValueChanged(app, event)
            cellNum = 247;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_248
        function Button_248ValueChanged(app, event)
            cellNum = 248;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_249
        function Button_249ValueChanged(app, event)
            cellNum = 249;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_250
        function Button_250ValueChanged(app, event)
            cellNum = 250;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_251
        function Button_251ValueChanged(app, event)
            cellNum = 251;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_252
        function Button_252ValueChanged(app, event)
            cellNum = 252;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_253
        function Button_253ValueChanged(app, event)
            cellNum = 253;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_254
        function Button_254ValueChanged(app, event)
            cellNum = 254;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_255
        function Button_255ValueChanged(app, event)
            cellNum = 255;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_256
        function Button_256ValueChanged(app, event)
            cellNum = 256;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Value changed function: Button_257
        function Button_257ValueChanged(app, event)
            cellNum = 257;
            if (app.CellSelectionList(cellNum) == 0)
                app.SelectCell(cellNum);
            else
                app.DeselectCell(cellNum);
            end
        end

        % Button down function: CellViewTab
        function CellViewTabButtonDown(app, event)
            app.ViewMode = 'Cell View';

            % Set all cell buttons to default color
            for i=1:257
                app.cellButtonList(i).BackgroundColor = app.DefaultButtonColor;
            end
        end

        % Button down function: ModuleViewTab
        function ModuleViewTabButtonDown(app, event)
            % Set current view mode to Module View
            app.ViewMode = 'Module View';
            
            % Color buttons with module colors
            app.ColorModules();

            % Set selected cell(s) fully defined lamp
                LampColor = 'g';
                for i=1:257
                    if app.CellSelectionList(i)
                        if app.CellList(i).fullyDefined == 0
                            LampColor = 'r';
                        end
                    end
                end
                app.ModuleFullyDefinedLamp.Color = LampColor;

                % Make add cell(s) to module button clickable
                if LampColor == 'g'
                    app.AddcellstomoduleButton.Enable = 'on';
                else
                    app.AddcellstomoduleButton.Enable = 'off';
                end
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            for i=1:257
                app.SelectCell(i);
            end
        end

        % Button pushed function: DeselectAllButton
        function DeselectAllButtonPushed(app, event)
            for i=1:257
                app.DeselectCell(i);
            end
        end

        % Button pushed function: WriteParametersButton
        function WriteParametersButtonPushed(app, event)
            % Write global parameters to every cell
            for i = 1:257
                app.CellList(i).eta = app.IdealityFactorEditField.Value;
                app.CellList(i).Is = app.SaturationCurrentEditField.Value;
                app.CellList(i).R = app.SeriesResistanceEditField.Value;
                app.CellList(i).IoptMax = app.MaxOpticalCurrentEditField.Value;
                app.CellList(i).T = app.TemperatureCEditField.Value;
            end
            
            % Update fully defined lamp for active cell
            if app.CellList(app.ActiveCellNum).fullyDefined
                app.CellFullyDefinedLamp.Color = 'g';
            end
        end

        % Button pushed function: WriteParametersButton_2
        function WriteParametersButton_2Pushed(app, event)
            % Write angle parameters for selected cells
            for i = 1:257
                if app.CellSelectionList(i) == 1
                    app.CellList(i).theta = app.PitchAngleEditField.Value;
                    app.CellList(i).phi = app.RollAngleEditField.Value;
                end
            end

            % Update cell fully defined lamp
            if app.CellList(app.ActiveCellNum).fullyDefined
                app.CellFullyDefinedLamp.Color = 'g';
            end

            % Update angle gauges
            app.CellPitchAngleGauge.Value = app.PitchAngleEditField.Value;
            app.CellRollAngleGauge.Value = app.RollAngleEditField.Value;   
        end

        % Button pushed function: PlotCellButton
        function PlotCellButtonPushed(app, event)
            if (app.CellList(app.ActiveCellNum).fullyDefined)
                cell = app.CellList(app.ActiveCellNum);

                cell.GenerateCurve(app.LightIntensitySlider.Value, app.SunZenithSlider.Value, app.SunAzimuthSlider.Value);

                I = linspace(-1, 10, 11001);
                V = cell.viVector;
                P = cell.GetPowerI(I);

                [Vmpp, Impp, Pmpp] = cell.GetMPP();
                
                hold(app.UIAxes, 'on')
                yyaxis(app.UIAxes, 'left')
                plot(app.UIAxes, V, I)                      % Plot current as a function of voltage
                %plot(app.UIAxes, [Vmpp Vmpp], ylim, 'r-')   % plot MPP voltage
                %plot(app.UIAxes, xlim, [Impp Impp], 'r-')   % plot MPP current
                %plot(app.UIAxes, [0 0], ylim, 'k-')         % plot y-axis
                %plot(app.UIAxes, xlim, [0 0], 'k-')         % plot x-axis
                yyaxis(app.UIAxes, 'right')
                plot(app.UIAxes, V, P)                      % plot power as a function of voltage
                ylabel(app.UIAxes, 'Power (W)')
                hold(app.UIAxes, 'off')


                app.OpenCircuitVoltageEditField.Value = cell.GetVoc();
                app.ShortCircuitCurrentEditField.Value = cell.GetIsc();
                
                % Update power fields
                app.MPPVoltageEditField.Value = Vmpp;
                app.MPPCurrentEditField.Value = Impp;
                app.MPPPowerEditField.Value = Pmpp;

                % Update current fields
                app.FullCurrentEditField.Value = (cell.GetIsc() / app.MaxOpticalCurrentEditField.Value) * 100;
            end
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
            yyaxis(app.UIAxes, 'left')
            cla(app.UIAxes)
            yyaxis(app.UIAxes, 'right')
            cla(app.UIAxes)
        end

        % Button pushed function: AddNewModuleButton
        function AddNewModuleButtonPushed(app, event)
            % Increment module count by 1
            app.numModules = app.numModules + 1;
            % Add module name to Module List
            app.ModuleListListBox.Items = [app.ModuleListListBox.Items, app.ModuleNameEditField.Value];
            % Add Cluster to Module List with id equal to numModules, name,
            % and color according to numModules index in ColorOrder
            app.ModuleListListBox.ItemsData = [app.ModuleListListBox.ItemsData, Cluster.CreateCluster(app.numModules, app.ModuleNameEditField.Value, ...
                                                                            app.ColorOrder{mod(app.numModules, size(app.ColorOrder, 2))})];
        end

        % Value changed function: ModuleListListBox
        function ModuleListListBoxValueChanged(app, event)
            % Change color of indicator lamp
            app.ModuleColorLamp.Color = app.ModuleListListBox.Value.color;

            % Set module size display
            app.ModuleSizeEditField.Value = string(app.ModuleListListBox.Value.size);

            % Set module name edit field
            app.ModuleNameEditField.Value = app.ModuleListListBox.Value.name;

            % Set bypass diode switch
            app.BypassDiodeSwitch.Value = int2str(app.ModuleListListBox.Value.bypassPresent);

            % Set selected cell(s) fully defined lamp
            if sum(app.CellSelectionList) > 0
                LampColor = 'g';
                for i=1:257
                    if app.CellSelectionList(i)
                        if app.CellList(i).fullyDefined == 0
                            LampColor = 'r';
                        end
                    end
                end
            else
                LampColor = 'r';
            end
            app.ModuleFullyDefinedLamp.Color = LampColor;

            % Make add cell(s) to module button clickable
            if LampColor == 'g'
                app.AddcellstomoduleButton.Enable = 'on';
            else
                app.AddcellstomoduleButton.Enable = 'off';
            end
        end

        % Button pushed function: DeleteModuleButton
        function DeleteModuleButtonPushed(app, event)
            % Set parentId of every cell in module to 0
            for i=1:app.ModuleListListBox.Value.size
                app.ModuleListListBox.Value.SolarObjects{i}.parentId = 0;
            end
            
            [~,idx] = ismember(app.ModuleListListBox.Value,app.ModuleListListBox.ItemsData);
            app.ModuleListListBox.Items(idx) = [];
            app.ModuleListListBox.ItemsData(idx) = [];
        end

        % Button pushed function: AddcellstomoduleButton
        function AddcellstomoduleButtonPushed(app, event)
       
            % Add selected cells to module
            for i = 1:257
                if app.CellSelectionList(i)
                    % Check if cell already belongs to a module
                    if (app.CellList(i).parentId == 0)
                        app.ModuleListListBox.Value.AddObject(app.CellList(i));
                    end
                end
            end

            % Update module size display
            app.ModuleSizeEditField.Value = int2str(app.ModuleListListBox.Value.size);

            % Update button colors
            app.ColorModules();

        end

        % Button pushed function: ExportArrayButton
        function ExportArrayButtonPushed(app, event)
            CellListLocal = app.CellList;
            ModuleNamesLocal = app.ModuleListListBox.Items;
            ModuleListLocal = app.ModuleListListBox.ItemsData;
            SubarrayNamesLocal = app.SubarrayListListBox.Items;
            SubarrayListLocal = app.SubarrayListListBox.ItemsData;
            numModulesLocal = app.numModules;
            numSubarraysLocal = app.numSubarrays;
            save(app.ArrayNameEditField.Value, 'CellListLocal', 'ModuleNamesLocal', 'ModuleListLocal', 'SubarrayNamesLocal', ...
                                                'SubarrayListLocal', 'numModulesLocal', 'numSubarraysLocal');
        end

        % Button pushed function: LoadArrayButton
        function LoadArrayButtonPushed(app, event)
            load(strcat(app.ArrayNameEditField.Value, '.mat'), 'CellListLocal', 'ModuleNamesLocal', 'ModuleListLocal', 'SubarrayNamesLocal', ...
                                                                'SubarrayListLocal', 'numModulesLocal', 'numSubarraysLocal');
            app.CellList = CellListLocal;
            app.ModuleListListBox.Items = ModuleNamesLocal;
            app.ModuleListListBox.ItemsData = ModuleListLocal;
            app.SubarrayListListBox.Items = SubarrayNamesLocal;
            app.SubarrayListListBox.ItemsData = SubarrayListLocal;
            app.numModules = numModulesLocal;
            app.numSubarrays = numSubarraysLocal;
        end

        % Value changed function: BypassDiodeSwitch
        function BypassDiodeSwitchValueChanged(app, event)
            value = app.BypassDiodeSwitch.Value;
            app.ModuleListListBox.Value.bypassPresent = str2num(value);
        end

        % Button pushed function: PlotModuleButton
        function PlotModuleButtonPushed(app, event)
            
            % Generate vector for selected module
            app.ModuleListListBox.Value.GenerateCurve(app.LightIntensitySlider.Value, app.SunZenithSlider.Value, app.SunAzimuthSlider.Value);

            I = linspace(-1, 10, 11001);
            V = app.ModuleListListBox.Value.viVector;
            P = app.ModuleListListBox.Value.GetPowerI(I);

            [Vmpp, Impp, Pmpp] = app.ModuleListListBox.Value.GetMPP();

            hold(app.UIAxes_2, 'on')
            yyaxis(app.UIAxes_2, 'left')
            plot(app.UIAxes_2, V, I)                      % Plot current as a function of voltage
            %plot(app.UIAxes, [Vmpp Vmpp], ylim, 'r-')   % plot MPP voltage
            %plot(app.UIAxes, xlim, [Impp Impp], 'r-')   % plot MPP current
            %plot(app.UIAxes, [0 0], ylim, 'k-')         % plot y-axis
            %plot(app.UIAxes, xlim, [0 0], 'k-')         % plot x-axis
            yyaxis(app.UIAxes_2, 'right')
            plot(app.UIAxes_2, V, P)                      % plot power as a function of voltage
            ylabel(app.UIAxes_2, 'Power (W)')
            hold(app.UIAxes_2, 'off')

            % Update power fields
            app.OpenCircuitVoltageEditField_2.Value = app.ModuleListListBox.Value.GetVoc();
            app.ShortCircuitCurrentEditField_2.Value = app.ModuleListListBox.Value.GetIsc();
            app.MPPVoltageEditField_2.Value = Vmpp;
            app.MPPCurrentEditField_2.Value = Impp;
            app.MPPPowerEditField_2.Value = Pmpp;

        end

        % Button pushed function: ClearPlotButton_2
        function ClearPlotButton_2Pushed(app, event)
            yyaxis(app.UIAxes_2, 'left')
            cla(app.UIAxes_2)
            yyaxis(app.UIAxes_2, 'right')
            cla(app.UIAxes_2)
        end

        % Button down function: SubarrayViewTab
        function SubarrayViewTabButtonDown(app, event)
            
            % Populate module list with list of modules not currently in
            % a subarray
            for i=1:app.numModules
                if app.ModuleListListBox.ItemsData{i}.parentId == 0
                    app.ModuleListListBox_2.Items = app.ModuleListListBox.Items;
                    app.ModuleListListBox_2.ItemsData = app.ModuleListListBox.ItemsData;
                end
            end
            
            % Color buttons with subarray colors
            app.ColorSubarrays();

        end

        % Button pushed function: AddNewSubarrayButton
        function AddNewSubarrayButtonPushed(app, event)
            
            % Increment subarray count by 1
            app.numSubarrays = app.numSubarrays + 1;
            % Add subarray name to subarray list
            app.SubarrayListListBox.Items = [app.SubarrayListListBox.Items, app.SubarrayNameEditField.Value];
            % Add Cluster to Subarray List with id equal to numSubarrays, name,
            % and color according to numSubarrays index in ColorOrder
            app.SubarrayListListBox.ItemsData = [app.SubarrayListListBox.ItemsData, Cluster.CreateCluster(app.numSubarrays, app.SubarrayNameEditField.Value, ...
                                                                            app.ColorOrder{mod(app.numSubarrays, size(app.ColorOrder, 2))})];
            
        end

        % Button pushed function: DeleteSubarrayButton
        function DeleteSubarrayButtonPushed(app, event)
            
            % Decrement subarray count
            app.numSubarrays = app.numSubarrays - 1;

            % Set parentId of every module in subarray to 0
            for i=1:app.SubarrayListListBox.Value.size
                app.SubarrayListListBox.Value.SolarObjects{i}.parentId = 0;
            end
            
            % Remove subarray from subarray list
            [~,idx] = ismember(app.SubarrayListListBox.Value, app.SubarrayListListBox.ItemsData);
            app.SubarrayListListBox.Items(idx) = [];
            app.SubarrayListListBox.ItemsData(idx) = [];

        end

        % Value changed function: SubarrayListListBox
        function SubarrayListListBoxValueChanged(app, event)

            % Change color of indicator lamp
            app.SubarrayColorLamp.Color = app.SubarrayListListBox.Value.color;

            % Set subarray size display
            app.SubarraySizeEditField.Value = app.SubarrayListListBox.Value.size;

            % Set subarray name edit field
            app.SubarrayNameEditField.Value = app.SubarrayListListBox.Value.name;

            % Clear Modules in Subarray list
            app.ModulesinSubarrayListBox.Items = {};
            app.ModulesinSubarrayListBox.ItemsData = {};

            % Populate Modules in Subarray list
            for i=1:app.SubarrayListListBox.Value.size
                app.ModulesinSubarrayListBox.Items = [app.ModulesinSubarrayListBox.Items, app.SubarrayListListBox.Value.SolarObjects{i}.name];
                app.ModulesinSubarrayListBox.ItemsData = [app.ModulesinSubarrayListBox.ItemsData, app.SubarrayListListBox.Value.SolarObjects{i}];
            end

        end

        % Button pushed function: PlotSubarrayButton
        function PlotSubarrayButtonPushed(app, event)
            
            % Generate vector for selected subarray
            app.SubarrayListListBox.Value.GenerateCurve(app.LightIntensitySlider.Value, app.SunZenithSlider.Value, app.SunAzimuthSlider.Value);

            I = linspace(-1, 10, 11001);
            V = app.SubarrayListListBox.Value.viVector;
            P = app.SubarrayListListBox.Value.GetPowerI(I);

            [Vmpp, Impp, Pmpp] = app.SubarrayListListBox.Value.GetMPP();

            hold(app.UIAxes_3, 'on')
            yyaxis(app.UIAxes_3, 'left')
            plot(app.UIAxes_3, V, I)                      % Plot current as a function of voltage
            %plot(app.UIAxes, [Vmpp Vmpp], ylim, 'r-')   % plot MPP voltage
            %plot(app.UIAxes, xlim, [Impp Impp], 'r-')   % plot MPP current
            %plot(app.UIAxes, [0 0], ylim, 'k-')         % plot y-axis
            %plot(app.UIAxes, xlim, [0 0], 'k-')         % plot x-axis
            yyaxis(app.UIAxes_3, 'right')
            plot(app.UIAxes_3, V, P)                      % plot power as a function of voltage
            ylabel(app.UIAxes_3, 'Power (W)')
            hold(app.UIAxes_3, 'off')

            % Update power fields
            app.OpenCircuitVoltageEditField_3.Value = app.SubarrayListListBox.Value.GetVoc();
            app.ShortCircuitCurrentEditField_3.Value = app.SubarrayListListBox.Value.GetIsc();
            app.MPPVoltageEditField_3.Value = Vmpp;
            app.MPPCurrentEditField_3.Value = Impp;
            app.MPPPowerEditField_3.Value = Pmpp;

        end

        % Button pushed function: InsertIntoSubarrayButton
        function InsertIntoSubarrayButtonPushed(app, event)
            
            % Add selected modules in module list to selected subarray and
            % modules in subarray list
            for i=1:size(app.ModuleListListBox_2.Value, 2)
                if (app.ModuleListListBox_2.Value(i).parentId == 0)
                    app.SubarrayListListBox.Value.AddObject(app.ModuleListListBox_2.Value(i));
                    app.ModulesinSubarrayListBox.Items = [app.ModulesinSubarrayListBox.Items, app.ModuleListListBox_2.Value(i).name];
                end
            end
            
            % Update subarray size display
            app.SubarraySizeEditField.Value = app.SubarrayListListBox.Value.size;

        end

        % Button pushed function: RemoveFromSubarrayButton
        function RemoveFromSubarrayButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GlobalParametersPanel = uipanel(app.UIFigure);
            app.GlobalParametersPanel.Title = 'Global Parameters';
            app.WriteParametersButton = uibutton(app.GlobalParametersPanel, 'push');
            app.WriteParametersButton.ButtonPushedFcn = createCallbackFcn(app, @WriteParametersButtonPushed, true);
            app.WriteParametersButton.Text = 'Write Parameters';
            app.GlobalCellParametersLabel = uilabel(app.GlobalParametersPanel);
            app.GlobalCellParametersLabel.Text = 'Global Cell Parameters';
            app.IdealityFactorEditFieldLabel = uilabel(app.GlobalParametersPanel);
            app.IdealityFactorEditFieldLabel.Text = 'Ideality Factor';
            app.IdealityFactorEditField = uieditfield(app.GlobalParametersPanel, 'numeric');
            app.IdealityFactorEditField.Value = 1.7;
            app.SaturationCurrentEditFieldLabel = uilabel(app.GlobalParametersPanel);
            app.SaturationCurrentEditFieldLabel.Text = 'Saturation Current';
            app.SaturationCurrentEditField = uieditfield(app.GlobalParametersPanel, 'numeric');
            app.SaturationCurrentEditField.Value = 6.4e-08;
            app.SeriesResistanceEditFieldLabel = uilabel(app.GlobalParametersPanel);
            app.SeriesResistanceEditFieldLabel.Text = 'Series Resistance';
            app.SeriesResistanceEditField = uieditfield(app.GlobalParametersPanel, 'numeric');
            app.SeriesResistanceEditField.Value = 0.01;
            app.MaxOpticalCurrentEditFieldLabel = uilabel(app.GlobalParametersPanel);
            app.MaxOpticalCurrentEditFieldLabel.Text = 'Max Optical Current';
            app.MaxOpticalCurrentEditField = uieditfield(app.GlobalParametersPanel, 'numeric');
            app.MaxOpticalCurrentEditField.Value = 6.18;
            app.TemperatureCEditFieldLabel = uilabel(app.GlobalParametersPanel);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (C)';
            app.TemperatureCEditField = uieditfield(app.GlobalParametersPanel, 'numeric');
            app.TemperatureCEditField.Value = 298;
            app.LightIntensitySlider_2Label = uilabel(app.GlobalParametersPanel);
            app.LightIntensitySlider_2Label.Text = 'Light Intensity';
            app.LightIntensitySlider = uislider(app.GlobalParametersPanel);
            app.LightIntensitySlider.Limits = [0 1];
            app.LightIntensitySlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.LightIntensitySlider.Value = 1;
            app.SunZenithSlider = uislider(app.GlobalParametersPanel);
            app.SunZenithSlider.Limits = [0 90];
            app.SunAzimuthSlider = uislider(app.GlobalParametersPanel);
            app.SunAzimuthSlider.Limits = [0 360];
            app.LightIntensitySlider_2Label_2 = uilabel(app.GlobalParametersPanel);
            app.LightIntensitySlider_2Label_2.Text = 'Sun Zenith';
            app.LightIntensitySlider_2Label_3 = uilabel(app.GlobalParametersPanel);
            app.LightIntensitySlider_2Label_3.Text = 'Sun Azimuth';
            app.ArrayPlannerPanel = uipanel(app.UIFigure);
            app.ArrayPlannerPanel.Title = 'Array Planner';
            app.Button_1 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_1.ValueChangedFcn = createCallbackFcn(app, @Button_1ValueChanged, true);
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Text = '';
            app.Button_12 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_12.ValueChangedFcn = createCallbackFcn(app, @Button_12ValueChanged, true);
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_13.ValueChangedFcn = createCallbackFcn(app, @Button_13ValueChanged, true);
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_14.ValueChangedFcn = createCallbackFcn(app, @Button_14ValueChanged, true);
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_15.ValueChangedFcn = createCallbackFcn(app, @Button_15ValueChanged, true);
            app.Button_15.Text = '';
            app.Button_20 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_20.ValueChangedFcn = createCallbackFcn(app, @Button_20ValueChanged, true);
            app.Button_20.Text = '';
            app.Button_21 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_21.ValueChangedFcn = createCallbackFcn(app, @Button_21ValueChanged, true);
            app.Button_21.Text = '';
            app.Button_22 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_22.ValueChangedFcn = createCallbackFcn(app, @Button_22ValueChanged, true);
            app.Button_22.Text = '';
            app.Button_23 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_23.ValueChangedFcn = createCallbackFcn(app, @Button_23ValueChanged, true);
            app.Button_23.Text = '';
            app.Button_9 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_10.ValueChangedFcn = createCallbackFcn(app, @Button_10ValueChanged, true);
            app.Button_10.Text = '';
            app.Button_16 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_16.ValueChangedFcn = createCallbackFcn(app, @Button_16ValueChanged, true);
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_17.ValueChangedFcn = createCallbackFcn(app, @Button_17ValueChanged, true);
            app.Button_17.Text = '';
            app.Button_24 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_24.ValueChangedFcn = createCallbackFcn(app, @Button_24ValueChanged, true);
            app.Button_24.Text = '';
            app.Button_25 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_25.ValueChangedFcn = createCallbackFcn(app, @Button_25ValueChanged, true);
            app.Button_25.Text = '';
            app.Button_18 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_18.ValueChangedFcn = createCallbackFcn(app, @Button_18ValueChanged, true);
            app.Button_18.Text = '';
            app.Button_26 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_26.ValueChangedFcn = createCallbackFcn(app, @Button_26ValueChanged, true);
            app.Button_26.Text = '';
            app.Button_11 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_11.ValueChangedFcn = createCallbackFcn(app, @Button_11ValueChanged, true);
            app.Button_11.Text = '';
            app.Button_19 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_19.ValueChangedFcn = createCallbackFcn(app, @Button_19ValueChanged, true);
            app.Button_19.Text = '';
            app.Button_27 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_27.ValueChangedFcn = createCallbackFcn(app, @Button_27ValueChanged, true);
            app.Button_27.Text = '';
            app.Button_28 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_28.ValueChangedFcn = createCallbackFcn(app, @Button_28ValueChanged, true);
            app.Button_28.Text = '';
            app.Button_29 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_29.ValueChangedFcn = createCallbackFcn(app, @Button_29ValueChanged, true);
            app.Button_29.Text = '';
            app.Button_33 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_33.ValueChangedFcn = createCallbackFcn(app, @Button_33ValueChanged, true);
            app.Button_33.Text = '';
            app.Button_34 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_34.ValueChangedFcn = createCallbackFcn(app, @Button_34ValueChanged, true);
            app.Button_34.Text = '';
            app.Button_37 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_37.ValueChangedFcn = createCallbackFcn(app, @Button_37ValueChanged, true);
            app.Button_37.Text = '';
            app.Button_38 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_38.ValueChangedFcn = createCallbackFcn(app, @Button_38ValueChanged, true);
            app.Button_38.Text = '';
            app.Button_41 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_41.ValueChangedFcn = createCallbackFcn(app, @Button_41ValueChanged, true);
            app.Button_41.Text = '';
            app.Button_42 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_42.ValueChangedFcn = createCallbackFcn(app, @Button_42ValueChanged, true);
            app.Button_42.Text = '';
            app.Button_45 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_45.ValueChangedFcn = createCallbackFcn(app, @Button_45ValueChanged, true);
            app.Button_45.Text = '';
            app.Button_46 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_46.ValueChangedFcn = createCallbackFcn(app, @Button_46ValueChanged, true);
            app.Button_46.Text = '';
            app.Button_49 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_49.ValueChangedFcn = createCallbackFcn(app, @Button_49ValueChanged, true);
            app.Button_49.Text = '';
            app.Button_50 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_50.ValueChangedFcn = createCallbackFcn(app, @Button_50ValueChanged, true);
            app.Button_50.Text = '';
            app.Button_53 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_53.ValueChangedFcn = createCallbackFcn(app, @Button_53ValueChanged, true);
            app.Button_53.Text = '';
            app.Button_54 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_54.ValueChangedFcn = createCallbackFcn(app, @Button_54ValueChanged, true);
            app.Button_54.Text = '';
            app.Button_57 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_57.ValueChangedFcn = createCallbackFcn(app, @Button_57ValueChanged, true);
            app.Button_57.Text = '';
            app.Button_58 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_58.ValueChangedFcn = createCallbackFcn(app, @Button_58ValueChanged, true);
            app.Button_58.Text = '';
            app.Button_61 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_61.ValueChangedFcn = createCallbackFcn(app, @Button_61ValueChanged, true);
            app.Button_61.Text = '';
            app.Button_62 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_62.ValueChangedFcn = createCallbackFcn(app, @Button_62ValueChanged, true);
            app.Button_62.Text = '';
            app.Button_65 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_65.ValueChangedFcn = createCallbackFcn(app, @Button_65ValueChanged, true);
            app.Button_65.Text = '';
            app.Button_66 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_66.ValueChangedFcn = createCallbackFcn(app, @Button_66ValueChanged, true);
            app.Button_66.Text = '';
            app.Button_69 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_69.ValueChangedFcn = createCallbackFcn(app, @Button_69ValueChanged, true);
            app.Button_69.Text = '';
            app.Button_70 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_70.ValueChangedFcn = createCallbackFcn(app, @Button_70ValueChanged, true);
            app.Button_70.Text = '';
            app.Button_71 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_71.ValueChangedFcn = createCallbackFcn(app, @Button_71ValueChanged, true);
            app.Button_71.Text = '';
            app.Button_75 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_75.ValueChangedFcn = createCallbackFcn(app, @Button_75ValueChanged, true);
            app.Button_75.Text = '';
            app.Button_76 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_76.ValueChangedFcn = createCallbackFcn(app, @Button_76ValueChanged, true);
            app.Button_76.Text = '';
            app.Button_77 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_77.ValueChangedFcn = createCallbackFcn(app, @Button_77ValueChanged, true);
            app.Button_77.Text = '';
            app.Button_81 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_81.ValueChangedFcn = createCallbackFcn(app, @Button_81ValueChanged, true);
            app.Button_81.Text = '';
            app.Button_82 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_82.ValueChangedFcn = createCallbackFcn(app, @Button_82ValueChanged, true);
            app.Button_82.Text = '';
            app.Button_83 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_83.ValueChangedFcn = createCallbackFcn(app, @Button_83ValueChanged, true);
            app.Button_83.Text = '';
            app.Button_87 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_87.ValueChangedFcn = createCallbackFcn(app, @Button_87ValueChanged, true);
            app.Button_87.Text = '';
            app.Button_88 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_88.ValueChangedFcn = createCallbackFcn(app, @Button_88ValueChanged, true);
            app.Button_88.Text = '';
            app.Button_89 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_89.ValueChangedFcn = createCallbackFcn(app, @Button_89ValueChanged, true);
            app.Button_89.Text = '';
            app.Button_90 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_90.ValueChangedFcn = createCallbackFcn(app, @Button_90ValueChanged, true);
            app.Button_90.Text = '';
            app.Button_96 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_96.ValueChangedFcn = createCallbackFcn(app, @Button_96ValueChanged, true);
            app.Button_96.Text = '';
            app.Button_97 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_97.ValueChangedFcn = createCallbackFcn(app, @Button_97ValueChanged, true);
            app.Button_97.Text = '';
            app.Button_98 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_98.ValueChangedFcn = createCallbackFcn(app, @Button_98ValueChanged, true);
            app.Button_98.Text = '';
            app.Button_99 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_99.ValueChangedFcn = createCallbackFcn(app, @Button_99ValueChanged, true);
            app.Button_99.Text = '';
            app.Button_105 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_105.ValueChangedFcn = createCallbackFcn(app, @Button_105ValueChanged, true);
            app.Button_105.Text = '';
            app.Button_106 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_106.ValueChangedFcn = createCallbackFcn(app, @Button_106ValueChanged, true);
            app.Button_106.Text = '';
            app.Button_107 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_107.ValueChangedFcn = createCallbackFcn(app, @Button_107ValueChanged, true);
            app.Button_107.Text = '';
            app.Button_108 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_108.ValueChangedFcn = createCallbackFcn(app, @Button_108ValueChanged, true);
            app.Button_108.Text = '';
            app.Button_91 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_91.ValueChangedFcn = createCallbackFcn(app, @Button_91ValueChanged, true);
            app.Button_91.Text = '';
            app.Button_100 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_100.ValueChangedFcn = createCallbackFcn(app, @Button_100ValueChanged, true);
            app.Button_100.Text = '';
            app.Button_109 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_109.ValueChangedFcn = createCallbackFcn(app, @Button_109ValueChanged, true);
            app.Button_109.Text = '';
            app.Button_31 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_31.ValueChangedFcn = createCallbackFcn(app, @Button_31ValueChanged, true);
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_32.ValueChangedFcn = createCallbackFcn(app, @Button_32ValueChanged, true);
            app.Button_32.Text = '';
            app.Button_30 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_30.ValueChangedFcn = createCallbackFcn(app, @Button_30ValueChanged, true);
            app.Button_30.Text = '';
            app.Button_35 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_35.ValueChangedFcn = createCallbackFcn(app, @Button_35ValueChanged, true);
            app.Button_35.Text = '';
            app.Button_36 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_36.ValueChangedFcn = createCallbackFcn(app, @Button_36ValueChanged, true);
            app.Button_36.Text = '';
            app.Button_39 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_39.ValueChangedFcn = createCallbackFcn(app, @Button_39ValueChanged, true);
            app.Button_39.Text = '';
            app.Button_40 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_40.ValueChangedFcn = createCallbackFcn(app, @Button_40ValueChanged, true);
            app.Button_40.Text = '';
            app.Button_43 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_43.ValueChangedFcn = createCallbackFcn(app, @Button_43ValueChanged, true);
            app.Button_43.Text = '';
            app.Button_44 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_44.ValueChangedFcn = createCallbackFcn(app, @Button_44ValueChanged, true);
            app.Button_44.Text = '';
            app.Button_47 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_47.ValueChangedFcn = createCallbackFcn(app, @Button_47ValueChanged, true);
            app.Button_47.Text = '';
            app.Button_48 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_48.ValueChangedFcn = createCallbackFcn(app, @Button_48ValueChanged, true);
            app.Button_48.Text = '';
            app.Button_51 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_51.ValueChangedFcn = createCallbackFcn(app, @Button_51ValueChanged, true);
            app.Button_51.Text = '';
            app.Button_52 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_52.ValueChangedFcn = createCallbackFcn(app, @Button_52ValueChanged, true);
            app.Button_52.Text = '';
            app.Button_55 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_55.ValueChangedFcn = createCallbackFcn(app, @Button_55ValueChanged, true);
            app.Button_55.Text = '';
            app.Button_56 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_56.ValueChangedFcn = createCallbackFcn(app, @Button_56ValueChanged, true);
            app.Button_56.Text = '';
            app.Button_59 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_59.ValueChangedFcn = createCallbackFcn(app, @Button_59ValueChanged, true);
            app.Button_59.Text = '';
            app.Button_60 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_60.ValueChangedFcn = createCallbackFcn(app, @Button_60ValueChanged, true);
            app.Button_60.Text = '';
            app.Button_63 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_63.ValueChangedFcn = createCallbackFcn(app, @Button_63ValueChanged, true);
            app.Button_63.Text = '';
            app.Button_64 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_64.ValueChangedFcn = createCallbackFcn(app, @Button_64ValueChanged, true);
            app.Button_64.Text = '';
            app.Button_67 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_67.ValueChangedFcn = createCallbackFcn(app, @Button_67ValueChanged, true);
            app.Button_67.Text = '';
            app.Button_68 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_68.ValueChangedFcn = createCallbackFcn(app, @Button_68ValueChanged, true);
            app.Button_68.Text = '';
            app.Button_73 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_73.ValueChangedFcn = createCallbackFcn(app, @Button_73ValueChanged, true);
            app.Button_73.Text = '';
            app.Button_74 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_74.ValueChangedFcn = createCallbackFcn(app, @Button_74ValueChanged, true);
            app.Button_74.Text = '';
            app.Button_79 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_79.ValueChangedFcn = createCallbackFcn(app, @Button_79ValueChanged, true);
            app.Button_79.Text = '';
            app.Button_80 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_80.ValueChangedFcn = createCallbackFcn(app, @Button_80ValueChanged, true);
            app.Button_80.Text = '';
            app.Button_85 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_85.ValueChangedFcn = createCallbackFcn(app, @Button_85ValueChanged, true);
            app.Button_85.Text = '';
            app.Button_86 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_86.ValueChangedFcn = createCallbackFcn(app, @Button_86ValueChanged, true);
            app.Button_86.Text = '';
            app.Button_95 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_95.ValueChangedFcn = createCallbackFcn(app, @Button_95ValueChanged, true);
            app.Button_95.Text = '';
            app.Button_92 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_92.ValueChangedFcn = createCallbackFcn(app, @Button_92ValueChanged, true);
            app.Button_92.Text = '';
            app.Button_104 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_104.ValueChangedFcn = createCallbackFcn(app, @Button_104ValueChanged, true);
            app.Button_104.Text = '';
            app.Button_101 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_101.ValueChangedFcn = createCallbackFcn(app, @Button_101ValueChanged, true);
            app.Button_101.Text = '';
            app.Button_113 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_113.ValueChangedFcn = createCallbackFcn(app, @Button_113ValueChanged, true);
            app.Button_113.Text = '';
            app.Button_110 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_110.ValueChangedFcn = createCallbackFcn(app, @Button_110ValueChanged, true);
            app.Button_110.Text = '';
            app.Button_93 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_93.ValueChangedFcn = createCallbackFcn(app, @Button_93ValueChanged, true);
            app.Button_93.Text = '';
            app.Button_102 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_102.ValueChangedFcn = createCallbackFcn(app, @Button_102ValueChanged, true);
            app.Button_102.Text = '';
            app.Button_111 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_111.ValueChangedFcn = createCallbackFcn(app, @Button_111ValueChanged, true);
            app.Button_111.Text = '';
            app.Button_72 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_72.ValueChangedFcn = createCallbackFcn(app, @Button_72ValueChanged, true);
            app.Button_72.Text = '';
            app.Button_78 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_78.ValueChangedFcn = createCallbackFcn(app, @Button_78ValueChanged, true);
            app.Button_78.Text = '';
            app.Button_84 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_84.ValueChangedFcn = createCallbackFcn(app, @Button_84ValueChanged, true);
            app.Button_84.Text = '';
            app.Button_94 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_94.ValueChangedFcn = createCallbackFcn(app, @Button_94ValueChanged, true);
            app.Button_94.Text = '';
            app.Button_103 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_103.ValueChangedFcn = createCallbackFcn(app, @Button_103ValueChanged, true);
            app.Button_103.Text = '';
            app.Button_112 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_112.ValueChangedFcn = createCallbackFcn(app, @Button_112ValueChanged, true);
            app.Button_112.Text = '';
            app.Button_114 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_114.ValueChangedFcn = createCallbackFcn(app, @Button_114ValueChanged, true);
            app.Button_114.Text = '';
            app.Button_115 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_115.ValueChangedFcn = createCallbackFcn(app, @Button_115ValueChanged, true);
            app.Button_115.Text = '';
            app.Button_116 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_116.ValueChangedFcn = createCallbackFcn(app, @Button_116ValueChanged, true);
            app.Button_116.Text = '';
            app.Button_117 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_117.ValueChangedFcn = createCallbackFcn(app, @Button_117ValueChanged, true);
            app.Button_117.Text = '';
            app.Button_123 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_123.ValueChangedFcn = createCallbackFcn(app, @Button_123ValueChanged, true);
            app.Button_123.Text = '';
            app.Button_124 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_124.ValueChangedFcn = createCallbackFcn(app, @Button_124ValueChanged, true);
            app.Button_124.Text = '';
            app.Button_125 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_125.ValueChangedFcn = createCallbackFcn(app, @Button_125ValueChanged, true);
            app.Button_125.Text = '';
            app.Button_126 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_126.ValueChangedFcn = createCallbackFcn(app, @Button_126ValueChanged, true);
            app.Button_126.Text = '';
            app.Button_132 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_132.ValueChangedFcn = createCallbackFcn(app, @Button_132ValueChanged, true);
            app.Button_132.Text = '';
            app.Button_133 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_133.ValueChangedFcn = createCallbackFcn(app, @Button_133ValueChanged, true);
            app.Button_133.Text = '';
            app.Button_134 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_134.ValueChangedFcn = createCallbackFcn(app, @Button_134ValueChanged, true);
            app.Button_134.Text = '';
            app.Button_135 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_135.ValueChangedFcn = createCallbackFcn(app, @Button_135ValueChanged, true);
            app.Button_135.Text = '';
            app.Button_118 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_118.ValueChangedFcn = createCallbackFcn(app, @Button_118ValueChanged, true);
            app.Button_118.Text = '';
            app.Button_127 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_127.ValueChangedFcn = createCallbackFcn(app, @Button_127ValueChanged, true);
            app.Button_127.Text = '';
            app.Button_136 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_136.ValueChangedFcn = createCallbackFcn(app, @Button_136ValueChanged, true);
            app.Button_136.Text = '';
            app.Button_122 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_122.ValueChangedFcn = createCallbackFcn(app, @Button_122ValueChanged, true);
            app.Button_122.Text = '';
            app.Button_119 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_119.ValueChangedFcn = createCallbackFcn(app, @Button_119ValueChanged, true);
            app.Button_119.Text = '';
            app.Button_131 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_131.ValueChangedFcn = createCallbackFcn(app, @Button_131ValueChanged, true);
            app.Button_131.Text = '';
            app.Button_128 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_128.ValueChangedFcn = createCallbackFcn(app, @Button_128ValueChanged, true);
            app.Button_128.Text = '';
            app.Button_140 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_140.ValueChangedFcn = createCallbackFcn(app, @Button_140ValueChanged, true);
            app.Button_140.Text = '';
            app.Button_137 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_137.ValueChangedFcn = createCallbackFcn(app, @Button_137ValueChanged, true);
            app.Button_137.Text = '';
            app.Button_120 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_120.ValueChangedFcn = createCallbackFcn(app, @Button_120ValueChanged, true);
            app.Button_120.Text = '';
            app.Button_129 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_129.ValueChangedFcn = createCallbackFcn(app, @Button_129ValueChanged, true);
            app.Button_129.Text = '';
            app.Button_138 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_138.ValueChangedFcn = createCallbackFcn(app, @Button_138ValueChanged, true);
            app.Button_138.Text = '';
            app.Button_121 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_121.ValueChangedFcn = createCallbackFcn(app, @Button_121ValueChanged, true);
            app.Button_121.Text = '';
            app.Button_130 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_130.ValueChangedFcn = createCallbackFcn(app, @Button_130ValueChanged, true);
            app.Button_130.Text = '';
            app.Button_139 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_139.ValueChangedFcn = createCallbackFcn(app, @Button_139ValueChanged, true);
            app.Button_139.Text = '';
            app.Button_141 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_141.ValueChangedFcn = createCallbackFcn(app, @Button_141ValueChanged, true);
            app.Button_141.Text = '';
            app.Button_142 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_142.ValueChangedFcn = createCallbackFcn(app, @Button_142ValueChanged, true);
            app.Button_142.Text = '';
            app.Button_143 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_143.ValueChangedFcn = createCallbackFcn(app, @Button_143ValueChanged, true);
            app.Button_143.Text = '';
            app.Button_144 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_144.ValueChangedFcn = createCallbackFcn(app, @Button_144ValueChanged, true);
            app.Button_144.Text = '';
            app.Button_145 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_145.ValueChangedFcn = createCallbackFcn(app, @Button_145ValueChanged, true);
            app.Button_145.Text = '';
            app.Button_149 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_149.ValueChangedFcn = createCallbackFcn(app, @Button_149ValueChanged, true);
            app.Button_149.Text = '';
            app.Button_146 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_146.ValueChangedFcn = createCallbackFcn(app, @Button_146ValueChanged, true);
            app.Button_146.Text = '';
            app.Button_147 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_147.ValueChangedFcn = createCallbackFcn(app, @Button_147ValueChanged, true);
            app.Button_147.Text = '';
            app.Button_148 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_148.ValueChangedFcn = createCallbackFcn(app, @Button_148ValueChanged, true);
            app.Button_148.Text = '';
            app.Button_150 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_150.ValueChangedFcn = createCallbackFcn(app, @Button_150ValueChanged, true);
            app.Button_150.Text = '';
            app.Button_151 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_151.ValueChangedFcn = createCallbackFcn(app, @Button_151ValueChanged, true);
            app.Button_151.Text = '';
            app.Button_152 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_152.ValueChangedFcn = createCallbackFcn(app, @Button_152ValueChanged, true);
            app.Button_152.Text = '';
            app.Button_153 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_153.ValueChangedFcn = createCallbackFcn(app, @Button_153ValueChanged, true);
            app.Button_153.Text = '';
            app.Button_159 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_159.ValueChangedFcn = createCallbackFcn(app, @Button_159ValueChanged, true);
            app.Button_159.Text = '';
            app.Button_160 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_160.ValueChangedFcn = createCallbackFcn(app, @Button_160ValueChanged, true);
            app.Button_160.Text = '';
            app.Button_161 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_161.ValueChangedFcn = createCallbackFcn(app, @Button_161ValueChanged, true);
            app.Button_161.Text = '';
            app.Button_162 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_162.ValueChangedFcn = createCallbackFcn(app, @Button_162ValueChanged, true);
            app.Button_162.Text = '';
            app.Button_168 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_168.ValueChangedFcn = createCallbackFcn(app, @Button_168ValueChanged, true);
            app.Button_168.Text = '';
            app.Button_169 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_169.ValueChangedFcn = createCallbackFcn(app, @Button_169ValueChanged, true);
            app.Button_169.Text = '';
            app.Button_170 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_170.ValueChangedFcn = createCallbackFcn(app, @Button_170ValueChanged, true);
            app.Button_170.Text = '';
            app.Button_171 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_171.ValueChangedFcn = createCallbackFcn(app, @Button_171ValueChanged, true);
            app.Button_171.Text = '';
            app.Button_154 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_154.ValueChangedFcn = createCallbackFcn(app, @Button_154ValueChanged, true);
            app.Button_154.Text = '';
            app.Button_163 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_163.ValueChangedFcn = createCallbackFcn(app, @Button_163ValueChanged, true);
            app.Button_163.Text = '';
            app.Button_172 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_172.ValueChangedFcn = createCallbackFcn(app, @Button_172ValueChanged, true);
            app.Button_172.Text = '';
            app.Button_158 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_158.ValueChangedFcn = createCallbackFcn(app, @Button_158ValueChanged, true);
            app.Button_158.Text = '';
            app.Button_155 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_155.ValueChangedFcn = createCallbackFcn(app, @Button_155ValueChanged, true);
            app.Button_155.Text = '';
            app.Button_167 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_167.ValueChangedFcn = createCallbackFcn(app, @Button_167ValueChanged, true);
            app.Button_167.Text = '';
            app.Button_164 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_164.ValueChangedFcn = createCallbackFcn(app, @Button_164ValueChanged, true);
            app.Button_164.Text = '';
            app.Button_176 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_176.ValueChangedFcn = createCallbackFcn(app, @Button_176ValueChanged, true);
            app.Button_176.Text = '';
            app.Button_173 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_173.ValueChangedFcn = createCallbackFcn(app, @Button_173ValueChanged, true);
            app.Button_173.Text = '';
            app.Button_156 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_156.ValueChangedFcn = createCallbackFcn(app, @Button_156ValueChanged, true);
            app.Button_156.Text = '';
            app.Button_165 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_165.ValueChangedFcn = createCallbackFcn(app, @Button_165ValueChanged, true);
            app.Button_165.Text = '';
            app.Button_174 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_174.ValueChangedFcn = createCallbackFcn(app, @Button_174ValueChanged, true);
            app.Button_174.Text = '';
            app.Button_157 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_157.ValueChangedFcn = createCallbackFcn(app, @Button_157ValueChanged, true);
            app.Button_157.Text = '';
            app.Button_166 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_166.ValueChangedFcn = createCallbackFcn(app, @Button_166ValueChanged, true);
            app.Button_166.Text = '';
            app.Button_175 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_175.ValueChangedFcn = createCallbackFcn(app, @Button_175ValueChanged, true);
            app.Button_175.Text = '';
            app.Button_177 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_177.ValueChangedFcn = createCallbackFcn(app, @Button_177ValueChanged, true);
            app.Button_177.Text = '';
            app.Button_178 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_178.ValueChangedFcn = createCallbackFcn(app, @Button_178ValueChanged, true);
            app.Button_178.Text = '';
            app.Button_179 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_179.ValueChangedFcn = createCallbackFcn(app, @Button_179ValueChanged, true);
            app.Button_179.Text = '';
            app.Button_180 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_180.ValueChangedFcn = createCallbackFcn(app, @Button_180ValueChanged, true);
            app.Button_180.Text = '';
            app.Button_181 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_181.ValueChangedFcn = createCallbackFcn(app, @Button_181ValueChanged, true);
            app.Button_181.Text = '';
            app.Button_185 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_185.ValueChangedFcn = createCallbackFcn(app, @Button_185ValueChanged, true);
            app.Button_185.Text = '';
            app.Button_182 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_182.ValueChangedFcn = createCallbackFcn(app, @Button_182ValueChanged, true);
            app.Button_182.Text = '';
            app.Button_183 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_183.ValueChangedFcn = createCallbackFcn(app, @Button_183ValueChanged, true);
            app.Button_183.Text = '';
            app.Button_184 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_184.ValueChangedFcn = createCallbackFcn(app, @Button_184ValueChanged, true);
            app.Button_184.Text = '';
            app.Button_186 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_186.ValueChangedFcn = createCallbackFcn(app, @Button_186ValueChanged, true);
            app.Button_186.Text = '';
            app.Button_187 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_187.ValueChangedFcn = createCallbackFcn(app, @Button_187ValueChanged, true);
            app.Button_187.Text = '';
            app.Button_188 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_188.ValueChangedFcn = createCallbackFcn(app, @Button_188ValueChanged, true);
            app.Button_188.Text = '';
            app.Button_189 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_189.ValueChangedFcn = createCallbackFcn(app, @Button_189ValueChanged, true);
            app.Button_189.Text = '';
            app.Button_195 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_195.ValueChangedFcn = createCallbackFcn(app, @Button_195ValueChanged, true);
            app.Button_195.Text = '';
            app.Button_196 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_196.ValueChangedFcn = createCallbackFcn(app, @Button_196ValueChanged, true);
            app.Button_196.Text = '';
            app.Button_197 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_197.ValueChangedFcn = createCallbackFcn(app, @Button_197ValueChanged, true);
            app.Button_197.Text = '';
            app.Button_198 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_198.ValueChangedFcn = createCallbackFcn(app, @Button_198ValueChanged, true);
            app.Button_198.Text = '';
            app.Button_204 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_204.ValueChangedFcn = createCallbackFcn(app, @Button_204ValueChanged, true);
            app.Button_204.Text = '';
            app.Button_205 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_205.ValueChangedFcn = createCallbackFcn(app, @Button_205ValueChanged, true);
            app.Button_205.Text = '';
            app.Button_206 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_206.ValueChangedFcn = createCallbackFcn(app, @Button_206ValueChanged, true);
            app.Button_206.Text = '';
            app.Button_207 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_207.ValueChangedFcn = createCallbackFcn(app, @Button_207ValueChanged, true);
            app.Button_207.Text = '';
            app.Button_190 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_190.ValueChangedFcn = createCallbackFcn(app, @Button_190ValueChanged, true);
            app.Button_190.Text = '';
            app.Button_199 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_199.ValueChangedFcn = createCallbackFcn(app, @Button_199ValueChanged, true);
            app.Button_199.Text = '';
            app.Button_208 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_208.ValueChangedFcn = createCallbackFcn(app, @Button_208ValueChanged, true);
            app.Button_208.Text = '';
            app.Button_194 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_194.ValueChangedFcn = createCallbackFcn(app, @Button_194ValueChanged, true);
            app.Button_194.Text = '';
            app.Button_191 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_191.ValueChangedFcn = createCallbackFcn(app, @Button_191ValueChanged, true);
            app.Button_191.Text = '';
            app.Button_203 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_203.ValueChangedFcn = createCallbackFcn(app, @Button_203ValueChanged, true);
            app.Button_203.Text = '';
            app.Button_200 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_200.ValueChangedFcn = createCallbackFcn(app, @Button_200ValueChanged, true);
            app.Button_200.Text = '';
            app.Button_212 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_212.ValueChangedFcn = createCallbackFcn(app, @Button_212ValueChanged, true);
            app.Button_212.Text = '';
            app.Button_209 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_209.ValueChangedFcn = createCallbackFcn(app, @Button_209ValueChanged, true);
            app.Button_209.Text = '';
            app.Button_192 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_192.ValueChangedFcn = createCallbackFcn(app, @Button_192ValueChanged, true);
            app.Button_192.Text = '';
            app.Button_201 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_201.ValueChangedFcn = createCallbackFcn(app, @Button_201ValueChanged, true);
            app.Button_201.Text = '';
            app.Button_210 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_210.ValueChangedFcn = createCallbackFcn(app, @Button_210ValueChanged, true);
            app.Button_210.Text = '';
            app.Button_193 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_193.ValueChangedFcn = createCallbackFcn(app, @Button_193ValueChanged, true);
            app.Button_193.Text = '';
            app.Button_202 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_202.ValueChangedFcn = createCallbackFcn(app, @Button_202ValueChanged, true);
            app.Button_202.Text = '';
            app.Button_211 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_211.ValueChangedFcn = createCallbackFcn(app, @Button_211ValueChanged, true);
            app.Button_211.Text = '';
            app.Button_213 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_213.ValueChangedFcn = createCallbackFcn(app, @Button_213ValueChanged, true);
            app.Button_213.Text = '';
            app.Button_214 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_214.ValueChangedFcn = createCallbackFcn(app, @Button_214ValueChanged, true);
            app.Button_214.Text = '';
            app.Button_215 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_215.ValueChangedFcn = createCallbackFcn(app, @Button_215ValueChanged, true);
            app.Button_215.Text = '';
            app.Button_216 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_216.ValueChangedFcn = createCallbackFcn(app, @Button_216ValueChanged, true);
            app.Button_216.Text = '';
            app.Button_217 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_217.ValueChangedFcn = createCallbackFcn(app, @Button_217ValueChanged, true);
            app.Button_217.Text = '';
            app.Button_221 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_221.ValueChangedFcn = createCallbackFcn(app, @Button_221ValueChanged, true);
            app.Button_221.Text = '';
            app.Button_218 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_218.ValueChangedFcn = createCallbackFcn(app, @Button_218ValueChanged, true);
            app.Button_218.Text = '';
            app.Button_219 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_219.ValueChangedFcn = createCallbackFcn(app, @Button_219ValueChanged, true);
            app.Button_219.Text = '';
            app.Button_220 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_220.ValueChangedFcn = createCallbackFcn(app, @Button_220ValueChanged, true);
            app.Button_220.Text = '';
            app.Button_222 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_222.ValueChangedFcn = createCallbackFcn(app, @Button_222ValueChanged, true);
            app.Button_222.Text = '';
            app.Button_223 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_223.ValueChangedFcn = createCallbackFcn(app, @Button_223ValueChanged, true);
            app.Button_223.Text = '';
            app.Button_224 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_224.ValueChangedFcn = createCallbackFcn(app, @Button_224ValueChanged, true);
            app.Button_224.Text = '';
            app.Button_225 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_225.ValueChangedFcn = createCallbackFcn(app, @Button_225ValueChanged, true);
            app.Button_225.Text = '';
            app.Button_231 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_231.ValueChangedFcn = createCallbackFcn(app, @Button_231ValueChanged, true);
            app.Button_231.Text = '';
            app.Button_232 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_232.ValueChangedFcn = createCallbackFcn(app, @Button_232ValueChanged, true);
            app.Button_232.Text = '';
            app.Button_233 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_233.ValueChangedFcn = createCallbackFcn(app, @Button_233ValueChanged, true);
            app.Button_233.Text = '';
            app.Button_234 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_234.ValueChangedFcn = createCallbackFcn(app, @Button_234ValueChanged, true);
            app.Button_234.Text = '';
            app.Button_240 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_240.ValueChangedFcn = createCallbackFcn(app, @Button_240ValueChanged, true);
            app.Button_240.Text = '';
            app.Button_241 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_241.ValueChangedFcn = createCallbackFcn(app, @Button_241ValueChanged, true);
            app.Button_241.Text = '';
            app.Button_242 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_242.ValueChangedFcn = createCallbackFcn(app, @Button_242ValueChanged, true);
            app.Button_242.Text = '';
            app.Button_243 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_243.ValueChangedFcn = createCallbackFcn(app, @Button_243ValueChanged, true);
            app.Button_243.Text = '';
            app.Button_226 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_226.ValueChangedFcn = createCallbackFcn(app, @Button_226ValueChanged, true);
            app.Button_226.Text = '';
            app.Button_235 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_235.ValueChangedFcn = createCallbackFcn(app, @Button_235ValueChanged, true);
            app.Button_235.Text = '';
            app.Button_244 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_244.ValueChangedFcn = createCallbackFcn(app, @Button_244ValueChanged, true);
            app.Button_244.Text = '';
            app.Button_230 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_230.ValueChangedFcn = createCallbackFcn(app, @Button_230ValueChanged, true);
            app.Button_230.Text = '';
            app.Button_227 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_227.ValueChangedFcn = createCallbackFcn(app, @Button_227ValueChanged, true);
            app.Button_227.Text = '';
            app.Button_239 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_239.ValueChangedFcn = createCallbackFcn(app, @Button_239ValueChanged, true);
            app.Button_239.Text = '';
            app.Button_236 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_236.ValueChangedFcn = createCallbackFcn(app, @Button_236ValueChanged, true);
            app.Button_236.Text = '';
            app.Button_248 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_248.ValueChangedFcn = createCallbackFcn(app, @Button_248ValueChanged, true);
            app.Button_248.Text = '';
            app.Button_245 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_245.ValueChangedFcn = createCallbackFcn(app, @Button_245ValueChanged, true);
            app.Button_245.Text = '';
            app.Button_228 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_228.ValueChangedFcn = createCallbackFcn(app, @Button_228ValueChanged, true);
            app.Button_228.Text = '';
            app.Button_237 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_237.ValueChangedFcn = createCallbackFcn(app, @Button_237ValueChanged, true);
            app.Button_237.Text = '';
            app.Button_246 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_246.ValueChangedFcn = createCallbackFcn(app, @Button_246ValueChanged, true);
            app.Button_246.Text = '';
            app.Button_229 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_229.ValueChangedFcn = createCallbackFcn(app, @Button_229ValueChanged, true);
            app.Button_229.Text = '';
            app.Button_238 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_238.ValueChangedFcn = createCallbackFcn(app, @Button_238ValueChanged, true);
            app.Button_238.Text = '';
            app.Button_247 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_247.ValueChangedFcn = createCallbackFcn(app, @Button_247ValueChanged, true);
            app.Button_247.Text = '';
            app.Button_249 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_249.ValueChangedFcn = createCallbackFcn(app, @Button_249ValueChanged, true);
            app.Button_249.Text = '';
            app.Button_250 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_250.ValueChangedFcn = createCallbackFcn(app, @Button_250ValueChanged, true);
            app.Button_250.Text = '';
            app.Button_251 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_251.ValueChangedFcn = createCallbackFcn(app, @Button_251ValueChanged, true);
            app.Button_251.Text = '';
            app.Button_252 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_252.ValueChangedFcn = createCallbackFcn(app, @Button_252ValueChanged, true);
            app.Button_252.Text = '';
            app.Button_253 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_253.ValueChangedFcn = createCallbackFcn(app, @Button_253ValueChanged, true);
            app.Button_253.Text = '';
            app.Button_257 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_257.ValueChangedFcn = createCallbackFcn(app, @Button_257ValueChanged, true);
            app.Button_257.Text = '';
            app.Button_254 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_254.ValueChangedFcn = createCallbackFcn(app, @Button_254ValueChanged, true);
            app.Button_254.Text = '';
            app.Button_255 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_255.ValueChangedFcn = createCallbackFcn(app, @Button_255ValueChanged, true);
            app.Button_255.Text = '';
            app.Button_256 = uibutton(app.ArrayPlannerPanel, 'state');
            app.Button_256.ValueChangedFcn = createCallbackFcn(app, @Button_256ValueChanged, true);
            app.Button_256.Text = '';
            app.SelectAllButton = uibutton(app.ArrayPlannerPanel, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.DeselectAllButton = uibutton(app.ArrayPlannerPanel, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Text = 'Deselect All';
            app.SingleSelectButton = uibutton(app.ArrayPlannerPanel, 'state');
            app.SingleSelectButton.Text = 'Single Select';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CellViewTab = uitab(app.TabGroup);
            app.CellViewTab.Title = 'Cell View';
            app.CellViewTab.ButtonDownFcn = createCallbackFcn(app, @CellViewTabButtonDown, true);
            app.UIAxes = uiaxes(app.CellViewTab);
            title(app.UIAxes, 'Cell IV Characteristics')
            xlabel(app.UIAxes, 'Voltage (V)')
            ylabel(app.UIAxes, 'Currenet (A)')
            zlabel(app.UIAxes, 'ZLabel')
            app.UIAxes.ZTickLabel = '';
            app.WriteParametersButton_2 = uibutton(app.CellViewTab, 'push');
            app.WriteParametersButton_2.ButtonPushedFcn = createCallbackFcn(app, @WriteParametersButton_2Pushed, true);
            app.WriteParametersButton_2.Text = 'Write Parameters';
            app.LocalCellParametersLabel = uilabel(app.CellViewTab);
            app.LocalCellParametersLabel.Text = 'Local Cell Parameters';
            app.PitchAngleEditFieldLabel = uilabel(app.CellViewTab);
            app.PitchAngleEditFieldLabel.Text = 'Pitch Angle';
            app.PitchAngleEditField = uieditfield(app.CellViewTab, 'numeric');
            app.RollAngleEditFieldLabel = uilabel(app.CellViewTab);
            app.RollAngleEditFieldLabel.Text = 'Roll Angle';
            app.RollAngleEditField = uieditfield(app.CellViewTab, 'numeric');
            app.PlotCellButton = uibutton(app.CellViewTab, 'push');
            app.PlotCellButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCellButtonPushed, true);
            app.PlotCellButton.Text = 'Plot Cell';
            app.CellFullyDefinedLamp = uilamp(app.CellViewTab);
            app.CellPitchAngleGaugeLabel = uilabel(app.CellViewTab);
            app.CellPitchAngleGaugeLabel.Text = 'Cell Pitch Angle';
            app.CellPitchAngleGauge = uigauge(app.CellViewTab, 'semicircular');
            app.CellPitchAngleGauge.Limits = [-90 90];
            app.CellPitchAngleGauge.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.CellRollAngleGaugeLabel = uilabel(app.CellViewTab);
            app.CellRollAngleGaugeLabel.Text = 'Cell Roll Angle';
            app.CellRollAngleGauge = uigauge(app.CellViewTab, 'semicircular');
            app.CellRollAngleGauge.Limits = [-90 90];
            app.CellRollAngleGauge.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.OpenCircuitVoltageEditFieldLabel = uilabel(app.CellViewTab);
            app.OpenCircuitVoltageEditFieldLabel.Text = 'Open-Circuit Voltage';
            app.OpenCircuitVoltageEditField = uieditfield(app.CellViewTab, 'numeric');
            app.OpenCircuitVoltageEditField.Editable = 'off';
            app.ShortCircuitCurrentEditFieldLabel = uilabel(app.CellViewTab);
            app.ShortCircuitCurrentEditFieldLabel.Text = 'Short-Circuit Current';
            app.ShortCircuitCurrentEditField = uieditfield(app.CellViewTab, 'numeric');

            app.ShortCircuitCurrentEditField.Editable = 'off';
            app.MPPVoltageEditFieldLabel = uilabel(app.CellViewTab);
            app.MPPVoltageEditFieldLabel.Text = 'MPP Voltage';
            app.MPPVoltageEditField = uieditfield(app.CellViewTab, 'numeric');
            app.MPPVoltageEditField.Editable = 'off';
            app.MPPCurrentEditFieldLabel = uilabel(app.CellViewTab);
            app.MPPCurrentEditFieldLabel.Text = 'MPP Current';
            app.MPPCurrentEditField = uieditfield(app.CellViewTab, 'numeric');
            app.MPPCurrentEditField.Editable = 'off';
            app.MPPPowerEditFieldLabel = uilabel(app.CellViewTab);
            app.MPPPowerEditFieldLabel.Text = 'MPP Power';
            app.MPPPowerEditField = uieditfield(app.CellViewTab, 'numeric');
            app.MPPPowerEditField.Editable = 'off';
            app.FullCurrentEditFieldLabel = uilabel(app.CellViewTab);
            app.FullCurrentEditFieldLabel.Text = '% Full Current';
            app.FullCurrentEditField = uieditfield(app.CellViewTab, 'numeric');
            app.FullCurrentEditField.Editable = 'off';
            app.SingleCellSelectedLamp = uilamp(app.CellViewTab);
            app.SingleCellSelectedLabel = uilabel(app.CellViewTab);
            app.SingleCellSelectedLabel.Text = 'Single Cell Selected';
            app.CellFullyDefinedLabel = uilabel(app.CellViewTab);
            app.CellFullyDefinedLabel.Text = 'Cell Fully Defined';
            app.VLabel = uilabel(app.CellViewTab);
            app.VLabel.Text = 'V';
            app.ALabel = uilabel(app.CellViewTab);
            app.ALabel.Text = 'A';
            app.VLabel_3 = uilabel(app.CellViewTab);
            app.VLabel_3.Text = 'V';
            app.ALabel_2 = uilabel(app.CellViewTab);
            app.ALabel_2.Text = 'A';
            app.WLabel = uilabel(app.CellViewTab);
            app.WLabel.Text = 'W';
            app.Label = uilabel(app.CellViewTab);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.CellViewTab);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.CellViewTab);
            app.Label_3.Text = '°';
            app.ClearPlotButton = uibutton(app.CellViewTab, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            app.ModuleViewTab = uitab(app.TabGroup);
            app.ModuleViewTab.Title = 'Module View';
            app.ModuleViewTab.ButtonDownFcn = createCallbackFcn(app, @ModuleViewTabButtonDown, true);
            app.UIAxes_2 = uiaxes(app.ModuleViewTab);
            title(app.UIAxes_2, 'Module IV Characteristics')
            xlabel(app.UIAxes_2, 'Voltage (V)')
            ylabel(app.UIAxes_2, 'Currenet (A)')
            zlabel(app.UIAxes_2, 'ZLabel')
            app.UIAxes_2.ZTickLabel = '';
            app.ModuleListListBoxLabel = uilabel(app.ModuleViewTab);
            app.ModuleListListBoxLabel.Text = 'Module List';
            app.ModuleListListBox = uilistbox(app.ModuleViewTab);
            app.ModuleListListBox.Items = {};
            app.ModuleListListBox.ValueChangedFcn = createCallbackFcn(app, @ModuleListListBoxValueChanged, true);
            app.ModuleListListBox.Value = {};
            app.ModuleNameEditFieldLabel = uilabel(app.ModuleViewTab);
            app.ModuleNameEditFieldLabel.Text = 'Module Name';
            app.ModuleNameEditField = uieditfield(app.ModuleViewTab, 'text');
            app.AddNewModuleButton = uibutton(app.ModuleViewTab, 'push');
            app.AddNewModuleButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewModuleButtonPushed, true);
            app.AddNewModuleButton.Text = 'Add New Module';
            app.AddcellstomoduleButton = uibutton(app.ModuleViewTab, 'push');
            app.AddcellstomoduleButton.ButtonPushedFcn = createCallbackFcn(app, @AddcellstomoduleButtonPushed, true);
            app.AddcellstomoduleButton.Enable = 'off';
            app.AddcellstomoduleButton.Text = 'Add cell(s) to module';
            app.BypassDiodeSwitchLabel = uilabel(app.ModuleViewTab);
            app.BypassDiodeSwitchLabel.Text = 'Bypass Diode';
            app.BypassDiodeSwitch = uiswitch(app.ModuleViewTab, 'toggle');
            app.BypassDiodeSwitch.Items = {'No', 'yes'};
            app.BypassDiodeSwitch.ItemsData = {'0', '1'};
            app.BypassDiodeSwitch.ValueChangedFcn = createCallbackFcn(app, @BypassDiodeSwitchValueChanged, true);
            app.BypassDiodeSwitch.Value = '0';
            app.ModuleSizeEditFieldLabel = uilabel(app.ModuleViewTab);
            app.ModuleSizeEditFieldLabel.Text = 'Module Size';
            app.ModuleSizeEditField = uieditfield(app.ModuleViewTab, 'text');
            app.ModuleSizeEditField.Editable = 'off';
            app.PlotModuleButton = uibutton(app.ModuleViewTab, 'push');
            app.PlotModuleButton.ButtonPushedFcn = createCallbackFcn(app, @PlotModuleButtonPushed, true);
            app.PlotModuleButton.Text = 'Plot Module';
            app.ClearPlotButton_2 = uibutton(app.ModuleViewTab, 'push');
            app.ClearPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButton_2Pushed, true);
            app.ClearPlotButton_2.Text = 'Clear Plot';
            app.VLabel_4 = uilabel(app.ModuleViewTab);
            app.VLabel_4.Text = 'V';
            app.ALabel_4 = uilabel(app.ModuleViewTab);
            app.ALabel_4.Text = 'A';
            app.VLabel_5 = uilabel(app.ModuleViewTab);
            app.VLabel_5.Text = 'V';
            app.ALabel_5 = uilabel(app.ModuleViewTab);
            app.ALabel_5.Text = 'A';
            app.WLabel_2 = uilabel(app.ModuleViewTab);
            app.WLabel_2.Text = 'W';
            app.OpenCircuitVoltageEditField_2Label = uilabel(app.ModuleViewTab);
            app.OpenCircuitVoltageEditField_2Label.Text = 'Open-Circuit Voltage';
            app.OpenCircuitVoltageEditField_2 = uieditfield(app.ModuleViewTab, 'numeric');
            app.OpenCircuitVoltageEditField_2.Editable = 'off';
            app.ShortCircuitCurrentEditField_2Label = uilabel(app.ModuleViewTab);
            app.ShortCircuitCurrentEditField_2Label.Text = 'Short-Circuit Current';
            app.ShortCircuitCurrentEditField_2 = uieditfield(app.ModuleViewTab, 'numeric');
            app.ShortCircuitCurrentEditField_2.Editable = 'off';
            app.MPPVoltageEditField_2Label = uilabel(app.ModuleViewTab);
            app.MPPVoltageEditField_2Label.Text = 'MPP Voltage';
            app.MPPVoltageEditField_2 = uieditfield(app.ModuleViewTab, 'numeric');
            app.MPPVoltageEditField_2.Editable = 'off';
            app.MPPCurrentEditField_2Label = uilabel(app.ModuleViewTab);
            app.MPPCurrentEditField_2Label.Text = 'MPP Current';
            app.MPPCurrentEditField_2 = uieditfield(app.ModuleViewTab, 'numeric');
            app.MPPCurrentEditField_2.Editable = 'off';
            app.MPPPowerEditField_2Label = uilabel(app.ModuleViewTab);
            app.MPPPowerEditField_2Label.Text = 'MPP Power';
            app.MPPPowerEditField_2 = uieditfield(app.ModuleViewTab, 'numeric');
            app.MPPPowerEditField_2.Editable = 'off';
            app.DeleteModuleButton = uibutton(app.ModuleViewTab, 'push');
            app.DeleteModuleButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteModuleButtonPushed, true);
            app.DeleteModuleButton.Text = 'Delete Module';
            app.ModuleColorLampLabel = uilabel(app.ModuleViewTab);
            app.ModuleColorLampLabel.Text = 'Module Color';
            app.ModuleColorLamp = uilamp(app.ModuleViewTab);
            app.ModuleFullyDefinedLamp = uilamp(app.ModuleViewTab);
            app.SelectedCellsFullyDefinedLabel = uilabel(app.ModuleViewTab);
            app.SelectedCellsFullyDefinedLabel.Text = 'Selected Cell(s) Fully Defined';
            app.SubarrayViewTab = uitab(app.TabGroup);
            app.SubarrayViewTab.Title = 'Subarray View';
            app.SubarrayViewTab.ButtonDownFcn = createCallbackFcn(app, @SubarrayViewTabButtonDown, true);
            app.UIAxes_3 = uiaxes(app.SubarrayViewTab);
            title(app.UIAxes_3, 'Subarray IV Characteristics')
            xlabel(app.UIAxes_3, 'Voltage (V)')
            ylabel(app.UIAxes_3, 'Currenet (A)')
            zlabel(app.UIAxes_3, 'ZLabel')
            app.UIAxes_3.ZTickLabel = '';
            app.ModuleListListBox_2Label = uilabel(app.SubarrayViewTab);
            app.ModuleListListBox_2Label.Text = 'Module List';
            app.ModuleListListBox_2 = uilistbox(app.SubarrayViewTab);
            app.ModuleListListBox_2.Items = {};
            app.ModuleListListBox_2.Multiselect = 'on';
            app.ModuleListListBox_2.Value = {};
            app.RemoveFromSubarrayButton = uibutton(app.SubarrayViewTab, 'push');
            app.RemoveFromSubarrayButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFromSubarrayButtonPushed, true);
            app.RemoveFromSubarrayButton.Text = '<';
            app.InsertIntoSubarrayButton = uibutton(app.SubarrayViewTab, 'push');
            app.InsertIntoSubarrayButton.ButtonPushedFcn = createCallbackFcn(app, @InsertIntoSubarrayButtonPushed, true);
            app.InsertIntoSubarrayButton.Text = '>';
            app.ModulesinSubarrayListBoxLabel = uilabel(app.SubarrayViewTab);
            app.ModulesinSubarrayListBoxLabel.Text = 'Modules in Subarray';
            app.ModulesinSubarrayListBox = uilistbox(app.SubarrayViewTab);
            app.ModulesinSubarrayListBox.Items = {};
            app.ModulesinSubarrayListBox.Value = {};
            app.MPPTProfileDropDownLabel = uilabel(app.SubarrayViewTab);
            app.MPPTProfileDropDownLabel.Text = 'MPPT Profile';
            app.MPPTProfileDropDown = uidropdown(app.SubarrayViewTab);
            app.MPPTProfileDropDown.Items = {'Ideal', 'Dilithium', 'AERL', 'Custom 1'};
            app.MPPTProfileDropDown.Value = 'Ideal';
            app.AddNewSubarrayButton = uibutton(app.SubarrayViewTab, 'push');
            app.AddNewSubarrayButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewSubarrayButtonPushed, true);
            app.AddNewSubarrayButton.Text = 'Add New Subarray';
            app.DeleteSubarrayButton = uibutton(app.SubarrayViewTab, 'push');
            app.DeleteSubarrayButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSubarrayButtonPushed, true);
            app.DeleteSubarrayButton.Text = 'Delete Subarray';
            app.SubarrayListListBoxLabel = uilabel(app.SubarrayViewTab);
            app.SubarrayListListBoxLabel.Text = 'Subarray List';
            app.SubarrayListListBox = uilistbox(app.SubarrayViewTab);
            app.SubarrayListListBox.Items = {};
            app.SubarrayListListBox.ValueChangedFcn = createCallbackFcn(app, @SubarrayListListBoxValueChanged, true);
            app.SubarrayListListBox.Value = {};
            app.SubarrayNameEditFieldLabel = uilabel(app.SubarrayViewTab);
            app.SubarrayNameEditFieldLabel.Text = 'Subarray Name';
            app.SubarrayNameEditField = uieditfield(app.SubarrayViewTab, 'text');
            app.SubarraySizeEditFieldLabel = uilabel(app.SubarrayViewTab);
            app.SubarraySizeEditFieldLabel.Text = 'Subarray Size';
            app.SubarraySizeEditField = uieditfield(app.SubarrayViewTab, 'numeric');
            app.SubarraySizeEditField.Editable = 'off';
            app.PlotSubarrayButton = uibutton(app.SubarrayViewTab, 'push');
            app.PlotSubarrayButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSubarrayButtonPushed, true);
            app.PlotSubarrayButton.Text = 'Plot Subarray';
            app.ClearPlotButton_3 = uibutton(app.SubarrayViewTab, 'push');
            app.ClearPlotButton_3.Text = 'Clear Plot';
            app.VLabel_6 = uilabel(app.SubarrayViewTab);
            app.VLabel_6.Text = 'V';
            app.ALabel_6 = uilabel(app.SubarrayViewTab);
            app.ALabel_6.Text = 'A';
            app.VLabel_7 = uilabel(app.SubarrayViewTab);
            app.VLabel_7.Text = 'V';
            app.ALabel_7 = uilabel(app.SubarrayViewTab);
            app.ALabel_7.Text = 'A';
            app.WLabel_3 = uilabel(app.SubarrayViewTab);
            app.WLabel_3.Text = 'W';
            app.OpenCircuitVoltageEditField_3Label = uilabel(app.SubarrayViewTab);
            app.OpenCircuitVoltageEditField_3Label.Text = 'Open-Circuit Voltage';
            app.OpenCircuitVoltageEditField_3 = uieditfield(app.SubarrayViewTab, 'numeric');
            app.OpenCircuitVoltageEditField_3.Editable = 'off';
            app.ShortCircuitCurrentEditField_3Label = uilabel(app.SubarrayViewTab);
            app.ShortCircuitCurrentEditField_3Label.Text = 'Short-Circuit Current';
            app.ShortCircuitCurrentEditField_3 = uieditfield(app.SubarrayViewTab, 'numeric');
            app.ShortCircuitCurrentEditField_3.Editable = 'off';
            app.MPPVoltageEditField_3Label = uilabel(app.SubarrayViewTab);
            app.MPPVoltageEditField_3Label.Text = 'MPP Voltage';
            app.MPPVoltageEditField_3 = uieditfield(app.SubarrayViewTab, 'numeric');
            app.MPPVoltageEditField_3.Editable = 'off';
            app.MPPCurrentEditField_3Label = uilabel(app.SubarrayViewTab);
            app.MPPCurrentEditField_3Label.Text = 'MPP Current';
            app.MPPCurrentEditField_3 = uieditfield(app.SubarrayViewTab, 'numeric');
            app.MPPCurrentEditField_3.Editable = 'off';
            app.MPPPowerEditField_3Label = uilabel(app.SubarrayViewTab);
            app.MPPPowerEditField_3Label.Text = 'MPP Power';
            app.MPPPowerEditField_3 = uieditfield(app.SubarrayViewTab, 'numeric');
            app.MPPPowerEditField_3.Editable = 'off';
            app.WLabel_4 = uilabel(app.SubarrayViewTab);
            app.WLabel_4.Text = 'W';
            app.Label_4 = uilabel(app.SubarrayViewTab);
            app.Label_4.Text = '%';
            app.CellsMaxPowerEditFieldLabel = uilabel(app.SubarrayViewTab);
            app.CellsMaxPowerEditFieldLabel.Text = 'Cells Max Power';
            app.CellsMaxPowerEditField = uieditfield(app.SubarrayViewTab, 'numeric');
            app.CellsMaxPowerEditField.Editable = 'off';
            app.TopologyEfficiencyEditFieldLabel = uilabel(app.SubarrayViewTab);
            app.TopologyEfficiencyEditFieldLabel.Text = 'Topology Efficiency';
            app.TopologyEfficiencyEditField = uieditfield(app.SubarrayViewTab, 'numeric');
            app.TopologyEfficiencyEditField.Editable = 'off';
            app.SubarrayColorLampLabel = uilabel(app.SubarrayViewTab);
            app.SubarrayColorLampLabel.Text = 'Subarray Color';
            app.SubarrayColorLamp = uilamp(app.SubarrayViewTab);
            app.ArrayViewTab = uitab(app.TabGroup);
            app.ArrayViewTab.Title = 'Array View';
            app.SavedArraysPanel = uipanel(app.UIFigure);
            app.SavedArraysPanel.Title = 'Saved Arrays';
            app.LoadArrayButton = uibutton(app.SavedArraysPanel, 'push');
            app.LoadArrayButton.ButtonPushedFcn = createCallbackFcn(app, @LoadArrayButtonPushed, true);
            app.LoadArrayButton.Text = 'Load Array';
            app.ExportArrayButton = uibutton(app.SavedArraysPanel, 'push');
            app.ExportArrayButton.ButtonPushedFcn = createCallbackFcn(app, @ExportArrayButtonPushed, true);
            app.ExportArrayButton.Text = 'Export Array';
            app.ArrayNameEditFieldLabel = uilabel(app.SavedArraysPanel);
            app.ArrayNameEditFieldLabel.Text = 'Array Name';
            app.ArrayNameEditField = uieditfield(app.SavedArraysPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimulatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end